classdef ZEALweb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZEALwebUIFiguree                matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        IMPORTTab                       matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        FIXEDSTRUCTUREPanel             matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        fixSwitch                       matlab.ui.control.Switch
        fixPDBIDEditField               matlab.ui.control.EditField
        fixPDBIDEditFieldLabel          matlab.ui.control.Label
        fixCHAINIDEditField             matlab.ui.control.EditField
        CHAINIDEditFieldLabel           matlab.ui.control.Label
        importFix                       matlab.ui.control.Button
        fixBrowseButton                 matlab.ui.control.Button
        ROTATINGSTRUCTUREPanel          matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        importRot                       matlab.ui.control.Button
        rotSwitch                       matlab.ui.control.Switch
        rotPDBIDEditFieldLabel          matlab.ui.control.Label
        rotCHAINIDEditFieldLabel        matlab.ui.control.Label
        rotCHAINIDEditField             matlab.ui.control.EditField
        rotPDBIDEditField               matlab.ui.control.EditField
        rotBrowseButton                 matlab.ui.control.Button
        LoaddemoButton                  matlab.ui.control.Button
        ALIGNTab                        matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        StartSearchButton               matlab.ui.control.Button
        StopSearchButton                matlab.ui.control.Button
        STOPPINGCRITERIAPanel           matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        funEvalOp_checkBox              matlab.ui.control.CheckBox
        execTimeOp_checkBox             matlab.ui.control.CheckBox
        FunctionevaluationsEditFieldLabel  matlab.ui.control.Label
        FunctionevaluationsEditField    matlab.ui.control.NumericEditField
        TimesEditFieldLabel             matlab.ui.control.Label
        TimesEditField                  matlab.ui.control.NumericEditField
        Panel_4                         matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        previousButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        nextButton                      matlab.ui.control.Button
        originalButton                  matlab.ui.control.Button
        finalButton                     matlab.ui.control.Button
        resetButton                     matlab.ui.control.Button
        UIAxes_optimPlot                matlab.ui.control.UIAxes
        EXPORTTab                       matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        WHEREPanel                      matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        WhereDropDown                   matlab.ui.control.DropDown
        EmailaddressEditFieldLabel      matlab.ui.control.Label
        EmailaddressEditField           matlab.ui.control.EditField
        SavePdbButton                   matlab.ui.control.Button
        WHATPanel                       matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        WhatDropDown                    matlab.ui.control.DropDown
        HETATOMSCheckBox                matlab.ui.control.CheckBox
        AllrecordsCheckBox              matlab.ui.control.CheckBox
        SETTINGSTab                     matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        DZERNIKEPARAMEERSPanel          matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        GridresolutionLabel             matlab.ui.control.Label
        GridresolutionEditField         matlab.ui.control.NumericEditField
        ZCmomentorderDropDownLabel      matlab.ui.control.Label
        ZCmomentorderDropDown           matlab.ui.control.DropDown
        SHAPEFUNCTIONPanel              matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        molSurfDropDown                 matlab.ui.control.DropDown
        ShapeRepDropDown                matlab.ui.control.DropDown
        Panel_2                         matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        SmearfactorEditField            matlab.ui.control.NumericEditField
        SmearfactorEditFieldLabel       matlab.ui.control.Label
        RelativeisovalueEditField       matlab.ui.control.NumericEditField
        RelativeisovalueEditFieldLabel  matlab.ui.control.Label
        shellThicknessLabel             matlab.ui.control.Label
        shellThicknessSpinner           matlab.ui.control.Spinner
        ProberadiusSpinnerLabel         matlab.ui.control.Label
        probeRadiusSpinner              matlab.ui.control.Spinner
        USEREGIONOFINTERESTPanel        matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        fixROICheckBox                  matlab.ui.control.CheckBox
        rotROICheckBox                  matlab.ui.control.CheckBox
        GRAPHICSTab                     matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        JSMOLPanel                      matlab.ui.container.Panel
        GridLayout29                    matlab.ui.container.GridLayout
        WebGLButton                     matlab.ui.control.StateButton
        ModeLabel                       matlab.ui.control.Label
        SurfacePanel                    matlab.ui.container.Panel
        GridLayout30                    matlab.ui.container.GridLayout
        ResolutionDropDownLabel         matlab.ui.control.Label
        ResolutionDropDown              matlab.ui.control.DropDown
        TranslucentButton               matlab.ui.control.StateButton
        BlendButton                     matlab.ui.control.StateButton
        ReloadJSmolButton               matlab.ui.control.Button
        TranslucentlevelDropDownLabel   matlab.ui.control.Label
        TranslucentlevelDropDown        matlab.ui.control.DropDown
        ZEALlogo                        matlab.ui.control.Image
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        JSMOLTab                        matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        HTML                            matlab.ui.control.HTML
        Panel_5                         matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        ZEALscoreResultLabel_2          matlab.ui.control.Label
        MoleculesButton                 matlab.ui.control.StateButton
        ShapefunctionButton             matlab.ui.control.StateButton
        Panel_6                         matlab.ui.container.Panel
        GridLayout27                    matlab.ui.container.GridLayout
        CartoonButton                   matlab.ui.control.StateButton
        BallandstickButton              matlab.ui.control.StateButton
        SpacefillButton                 matlab.ui.control.StateButton
        sasurfaceButton                 matlab.ui.control.StateButton
        MolecularsurfaceButton          matlab.ui.control.StateButton
        FixedButton                     matlab.ui.control.StateButton
        RotatingButton                  matlab.ui.control.StateButton
        waitSpinnerImage                matlab.ui.control.Image
        GridLayout31                    matlab.ui.container.GridLayout
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        article_icon                    matlab.ui.control.Image
        FilipLjungandIngemarAndrBioinformatics2021Label  matlab.ui.control.Label
        github_icon                     matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        
        ZEALfix % ZEAL object (single mode) for fixed
        ZEALrot  % ZEAL object (single mode) for rotating
        ZEALsettings % structure for setting parameters to ZEAL input parser
        ZEALalignment % data container for ZEALalignment object in ZEALalignment mode
        
        Session % container for variables pertaining to the GUI session
        
        JSmol % settings to JSmol
        
        optimPlot % results and status for handling the plot during surrogate optimization
        
        paths % store various libary paths here
        
        demo % data for running the demo
    end
    
    methods (Access = private)
        
        
        %% ------------- GUI -------------
        
        function enableSearch(app)
            
            app.StartSearchButton.Enable = true;
            
            app.TabGroup2.SelectedTab = app.JSMOLTab;
            app.TabGroup.SelectedTab = app.ALIGNTab;
            
        end
        
        function disableSearch(app)
            
            disableSetupTab(app);
            
            app.StartSearchButton.Enable = false;
            
        end
        
        function disableSetupTab(app)
            
            app.ZCmomentorderDropDown.Enable = false;
            app.ZCmomentorderDropDownLabel.Enable = false;
            
            app.ShapeRepDropDown.Enable = false;
            app.shellThicknessLabel.Enable = false;
            app.shellThicknessSpinner.Enable = false;
            
            app.ProberadiusSpinnerLabel.Enable = false;
            app.probeRadiusSpinner.Enable = false;
            
            app.SmearfactorEditField.Enable = false;
            app.SmearfactorEditFieldLabel.Enable = false;
            
            app.RelativeisovalueEditField.Enable = false;
            app.RelativeisovalueEditFieldLabel.Enable = false;
            
            app.GridresolutionEditField.Enable = false;
            app.GridresolutionLabel.Enable = false;
            
            app.molSurfDropDown.Enable = false;
            
        end
        
        function enableSetupTab(app)
            
            
            app.ZCmomentorderDropDown.Enable = true;
            app.ZCmomentorderDropDownLabel.Enable = true;
            
            app.ShapeRepDropDown.Enable = true;
            app.shellThicknessLabel.Enable = true;
            app.shellThicknessSpinner.Enable = true;
            
            app.ProberadiusSpinnerLabel.Enable = true;
            app.probeRadiusSpinner.Enable = true;
            
            app.SmearfactorEditField.Enable = true;
            app.SmearfactorEditFieldLabel.Enable = true;
            
            app.RelativeisovalueEditField.Enable = true;
            app.RelativeisovalueEditFieldLabel.Enable = true;
            
            app.GridresolutionEditField.Enable = true;
            app.GridresolutionLabel.Enable = true;
            
            app.molSurfDropDown.Enable = true;
            
            app.StartSearchButton.Enable = true;
            
        end
        
        
        % ------------- MISC -------------


        function pdbidOK = checkFixPDBIDinput(app)
            
            pdbidSel = app.fixPDBIDEditField.Value;
            
            % Check input
            if isempty(pdbidSel)
                uialert(app.ZEALwebUIFiguree, 'Please enter a PDB ID in the field before loading.','','icon', 'warning');
                pdbidOK = false;
                
            elseif length(pdbidSel)~=4
                uialert(app.ZEALwebUIFiguree, 'The PDB ID should be 4 characters long (''1ubq'' for instance to download Ubiquitin ).','','icon', 'warning');
                pdbidOK = false;
            else
                pdbidOK = true;
            end
        end
        
        function pdbidOK = checkRotPDBIDinput(app)
            
            pdbidSel = app.rotPDBIDEditField.Value;
            
            % Check input
            if isempty(pdbidSel)
                uialert(app.ZEALwebUIFiguree, 'Please enter a PDB ID in the field before loading.','','icon', 'warning');
                pdbidOK = false;
                
            elseif length(pdbidSel)~=4
                uialert(app.ZEALwebUIFiguree, 'The PDB ID should be 4 characters long (''1ubq'' for instance to download Ubiquitin ).','','icon', 'warning');
                pdbidOK = false;
            else
                pdbidOK = true;
            end
        end
        
        
        function resetFix(app)
            
            disableSetupTab(app);
            disableSearch(app);
            
            app.Session.fix.loaded = false;
            app.JSmol.fixSaved = false;
            
            app.demo.loadDemo = false;
            
            app.ZEALalignment.Score = [];
            app.Session.updatefix = true;
            
            updateResultLabel(app);
            
            resetOptimPlot(app);
            
            loadMode = app.fixSwitch.Value;
            
            app.JSmol.pauseTime = 1; % seconds to wait for JSmol to initiate until sending commands
            
            if isdeployed
                if strcmp(loadMode,'LOAD')
                    app.importFix.Icon = 'icon-mat-file-upload-0072bd.png';
                else
                    app.importFix.Icon = 'icon-mat-file-download-0072bd.png';
                end
            else
                if strcmp(loadMode,'LOAD')
                    app.importFix.Icon = fullfile(app.paths.icons, 'icon-mat-file-upload-0072bd.png');
                else
                    app.importFix.Icon = fullfile(app.paths.icons, 'icon-mat-file-download-0072bd.png');
                end
            end
            
            bgcol = [0.87 0.93 0.96];
            app.importFix.BackgroundColor = bgcol;
            
            app.fixCHAINIDEditField.BackgroundColor = bgcol;
            app.fixCHAINIDEditField.FontColor = [0 0 0];
            app.fixCHAINIDEditField.FontWeight = 'normal';
            
            app.fixPDBIDEditField.BackgroundColor = bgcol;
            app.fixPDBIDEditField.FontColor = [0 0 0];
            app.fixPDBIDEditField.FontWeight = 'normal';
            app.fixPDBIDEditField.Value = '';
            
            app.fixCHAINIDEditField.Value = '';
            
            if strcmp(loadMode,'LOAD')
                app.fixBrowseButton.Enable = true;
                app.fixBrowseButton.Visible = true;
                app.importFix.Text = 'Upload';
            else
                app.fixBrowseButton.Enable = false;
                app.fixBrowseButton.Visible = false;
                app.importFix.Text = 'Download';
            end


            app.Session.fix.name = '';
            
            % Update JSmol display
            scriptStr = sprintf('zap 1.1; isosurface off; select all; cartoon on');
            app.sasurfaceButton.Value = false;
            app.MolecularsurfaceButton.Value = false;
            
            pdbJSmolMatStruct.flag = '2'; % trigger JSmol script
            pdbJSmolMatStruct.scriptStr = scriptStr;
            app.HTML.Data = pdbJSmolMatStruct;
            
            app.CartoonButton.Value = true;
            
        end
        
        function resetRot(app)
            
            disableSetupTab(app);
            disableSearch(app);
            
            app.Session.rot.loaded = false;
            app.JSmol.rotSaved = false;
            
            app.ZEALalignment.Score = [];
            
            updateResultLabel(app);
            
            resetOptimPlot(app);
            
            loadMode = app.rotSwitch.Value;
            
            app.JSmol.pauseTime = 1; % seconds to wait for JSmol to initiate until sending commands
            
            
            if isdeployed
                if strcmp(loadMode,'LOAD')
                    app.importRot.Icon = 'icon-mat-file-upload-0072bd.png';
                else
                    app.importRot.Icon = 'icon-mat-file-download-0072bd.png';
                end
            else
                if strcmp(loadMode,'LOAD')
                    app.importRot.Icon = fullfile(app.paths.icons, 'icon-mat-file-upload-0072bd.png');
                else
                    app.importRot.Icon = fullfile(app.paths.icons, 'icon-mat-file-download-0072bd.png');
                end
            end
            
            
            bgcol = [0.87 0.93 0.96];
            app.importRot.BackgroundColor = bgcol;
            
            app.rotCHAINIDEditField.BackgroundColor = bgcol;
            app.rotCHAINIDEditField.FontColor = [0 0 0];
            app.rotCHAINIDEditField.FontWeight = 'normal';
            
            
            app.rotPDBIDEditField.BackgroundColor = bgcol;
            app.rotPDBIDEditField.FontColor = [0 0 0];
            app.rotPDBIDEditField.FontWeight = 'normal';
            app.rotPDBIDEditField.Value = '';
            
            app.rotCHAINIDEditField.Value = '';
            
            if strcmp(loadMode,'LOAD')
                app.rotBrowseButton.Enable = true;
                app.rotBrowseButton.Visible = true;
                app.importRot.Text = 'Upload';
            else
                app.rotBrowseButton.Enable = false;
                app.rotBrowseButton.Visible = false;
                app.importRot.Text = 'Download';
            end
            
            app.Session.rot.name = '';
            
            if app.Session.fix.loaded
                selStr = '1.2';
            else
                selStr = 'all';
            end
            
            scriptStr = sprintf('zap %s; isosurface off; select all; cartoon on', selStr);
            app.sasurfaceButton.Value = false;
            app.MolecularsurfaceButton.Value = false;
            
            % Send script command
            pdbJSmolMatStruct.flag = '2'; % trigger JSmol script
            pdbJSmolMatStruct.scriptStr = scriptStr;
            app.HTML.Data = pdbJSmolMatStruct;
            
            % Set default rep as cartoon
            app.CartoonButton.Value = true;
            
        end
        
        function updateResultLabel(app)
            
            ZEALscore_str = sprintf('Score: %5.5f',  app.ZEALalignment.Score);
            
            app.ZEALscoreResultLabel_2.Text = ZEALscore_str;
            
        end
        
        
        %% ------------- ZEALalignment SEARCH -------------
        
        function globalSearch(app)
            
            % If user start start from the history of solutions, update structure.
            if app.optimPlot.solCount > 1% ~= app.optimPlot.nSolutions
                updateBeforeSearch(app);
            end
            
            if app.ShapefunctionButton.Value
                app.HTML.Visible = false;
                app.waitSpinnerImage.Visible = true;
                app.ShapefunctionButton.Enable = false;
                app.MoleculesButton.Enable = false;
            end
            
            
            % ### PERFORM SURROGATE OPTIMIZATION ###
            
            disableHistory(app);
            
            shapeAlign(app.ZEALalignment)
            
            enableHistory(app);
            
            % ### HANDLE OUTPUT AFTER OPTIMIZATION ###
            x = app.ZEALalignment.Search.EulerAngles;
            
            Rmat = ZEAL.euler2rotMat(x);
            
            XYZ_new = app.ZEALalignment.rotating.molShape.AtomData(:,1:3) * Rmat;
            XYZ_orig = [app.ZEALalignment.rotating.PDB.Data.X app.ZEALalignment.rotating.PDB.Data.Y app.ZEALalignment.rotating.PDB.Data.Z];
            Rmat_fromOriginal = ZEAL.findRotation(XYZ_new', XYZ_orig');
            
            app.ZEALalignment.rotating.molShape.AtomData(:,1:3) = app.ZEALrot.fixed.molShape.AtomData(:,1:3) * Rmat_fromOriginal;
            
            % Update shape function
            computeShapeFunction(app.ZEALalignment.rotating.molShape);
            
            app.JSmol.rot.shapeFunctionDataUpdate = true;
            
            if app.ShapefunctionButton.Value
                app.HTML.Visible = true;
                app.waitSpinnerImage.Visible = false;
                app.ShapefunctionButton.Enable = true;
                app.MoleculesButton.Enable = true;
            end
            
            % update GUI
            app.SavePdbButton.Enable = true;
            
            if app.ShapefunctionButton.Value
                disableHistory(app);
            else
                enableHistory(app);
            end
            
        end
        
        function  stop = surrOptiOutputFcn(app,x,optimvalues,state)
            
            switch state
                
                case 'init'
                    
                    fprintf('\n\n -------------------------------------\n');
                    fprintf('\n\tPerforming shape ZEALalignment search.\n');
                    
                    app.UIAxes_optimPlot.XLim = [0 app.ZEALalignment.Settings.SurrOpt.MaxFunctionEvaluations];
                    app.optimPlot.ph=plot(app.UIAxes_optimPlot, app.optimPlot.iteration, app.optimPlot.fval);
                    app.optimPlot.ph.Marker = 'o';
                    
                    app.optimPlot.ph.MarkerSize = 4;
                    app.optimPlot.ph.MarkerEdgeColor = app.optimPlot.plotcolor;
                    app.optimPlot.ph.MarkerFaceColor = app.optimPlot.plotcolor;
                    app.optimPlot.ph.LineStyle='none';
                    
                    app.StartSearchButton.Enable = false;
                    app.StopSearchButton.Enable = true;
                    
                    app.Session.startTime = tic;
                    % rotation updates to JSmol not correct
                    
                    %app.Session.prev_val = -1*app.ZEALalignment.Score;
                    
                    app.Session.prev_val = 0;
                    
                    % init quaterion vector
                    if app.optimPlot.reset
                        app.optimPlot.quatZYZ_new = [0 0 0 0];
                        app.optimPlot.reset = false;
                        
                    end
                    
                    % init vector for storing solutions that improve obj function values during search
                    app.optimPlot.rot_history = zeros(app.ZEALalignment.Settings.SurrOpt.MaxFunctionEvaluations, 3);
                    app.optimPlot.zealscore_history = zeros(app.ZEALalignment.Settings.SurrOpt.MaxFunctionEvaluations, 1);
                    app.optimPlot.iteration_history = zeros(app.ZEALalignment.Settings.SurrOpt.MaxFunctionEvaluations, 1);
                    
                    app.optimPlot.rot.AtomData = app.ZEALalignment.rotating.molShape.AtomData;
                    % counter update plot events
                    app.optimPlot.updateCount = 1;
                    
                    app.optimPlot.rot_history(app.optimPlot.updateCount,:) = [0 0 0];
                    app.optimPlot.zealscore_history(app.optimPlot.updateCount,:) = app.ZEALalignment.Score;
                    app.optimPlot.iteration_history(app.optimPlot.updateCount) = 0;
                    
                    app.Session.Search.History.Iteration = [];
                    app.Session.Search.History.Score = [];
                    app.Session.Search.History.EulerAngles = [];
                    
                    app.Session.Search.History.prev_val = -1*app.ZEALalignment.Score;
                    app.Session.Search.History.startTime = tic;
                    app.Session.Search.History.BestScore = app.ZEALalignment.Score;
                    
                    fprintf('\n\n /////////////////////////////////////////////////////////////////////////////\n');
                    
                    fprintf('\n\tSearching for best shape superposition\n');
                    fprintf('\n\tStopping after %d function evaluations\n', app.ZEALalignment.Settings.SurrOpt.MaxFunctionEvaluations);
                    
                    fprintf('\n ----------------------------------------------------------------------------');
                    fprintf('\n         Best score      Euler (zyz)         iteration       time (s) ');
                    fprintf('\n ----------------------------------------------------------------------------');
                    
                case 'iter'
                    searchTime = toc(app.Session.Search.History.startTime);
                    
                    if optimvalues.fval < (app.Session.Search.History.prev_val*app.Session.Search.History.SearchSaveFactor)
                        
                        app.Session.Search.History.prev_val = optimvalues.fval;
                        
                        app.Session.Search.History.Iteration = [app.Session.Search.History.Iteration optimvalues.iteration];
                        app.Session.Search.History.EulerAngles = [app.Session.Search.History.EulerAngles; x];
                        app.Session.Search.History.Score = [app.Session.Search.History.Score -1*optimvalues.fval];
                        
                        app.Session.Search.History.BestScore = -1*optimvalues.fval;
                        app.Session.Search.History.BestTime = searchTime;
                        app.Session.Search.History.BestIteration = optimvalues.iteration;
                        app.Session.Search.History.BestEulerAngles = x;
                        
                        fprintf('\n\t %2.2f \t\t %2.2f %2.2f %2.2f \t %d \t\t %4.1f', app.Session.Search.History.BestScore, x(1), x(2), x(3), app.Session.Search.History.BestIteration, app.Session.Search.History.BestTime);
                        
                    end
                    
                case 'done'
                    
                    searchTime = toc(app.Session.Search.History.startTime);
                    
                    app.Session.Search.History.Iteration = [app.Session.Search.History.Iteration optimvalues.iteration];
                    app.Session.Search.History.EulerAngles = [app.Session.Search.History.EulerAngles; x];
                    app.Session.Search.History.Score = [app.Session.Search.History.Score -1*optimvalues.fval];
                    
                    app.Session.Search.History.BestScore = -1*optimvalues.fval;
                    app.Session.Search.History.BestTime = searchTime;
                    app.Session.Search.History.BestIteration = optimvalues.iteration;
                    app.Session.Search.History.BestEulerAngles = x;
                    
                    fprintf('\n ----------------------------------------------------------------------------');
                    
                    fprintf('\n\n\tSearch completed after %3.1f s.\n', searchTime);
                    fprintf('\n\tBest score %2.2f found after %d iterations (%3.1f s)\n', app.Session.Search.History.BestScore, app.Session.Search.History.BestIteration, app.Session.Search.History.BestTime);
                    fprintf('\n\tusing Euler angles (zyz) [%3.3f %3.3f %3.3f]', app.Session.Search.History.BestEulerAngles(1), app.Session.Search.History.BestEulerAngles(2), app.Session.Search.History.BestEulerAngles(3));
                    
                    fprintf('\n\n /////////////////////////////////////////////////////////////////////////////\n');
                    
                    app.StartSearchButton.Enable = true;
                    app.StopSearchButton.Enable = false;
                    
                    % remove zeros (=unused rows) in solution vector
                    rowsToRemove = app.optimPlot.updateCount+1:size(app.optimPlot.rot_history,1);
                    
                    app.optimPlot.rot_history(rowsToRemove,:) = [];
                    app.optimPlot.zealscore_history(rowsToRemove) = [];
                    app.optimPlot.iteration_history(rowsToRemove) = [];
                    
                    app.optimPlot.nSolutions = length(app.optimPlot.zealscore_history);
                    
                    app.optimPlot.solCount = app.optimPlot.nSolutions;
                    
                    app.optimPlot.nIters = optimvalues.iteration;
                    
                    app.UIAxes_optimPlot.XLim = [0 app.optimPlot.nIters];
                    
                    % Plot saved snapshots
                    hold(app.UIAxes_optimPlot,'on');
                    app.optimPlot.ph3=plot(app.UIAxes_optimPlot, app.optimPlot.iteration_history, app.optimPlot.zealscore_history,'o');
                    hold(app.UIAxes_optimPlot,'off');
                    
                    app.optimPlot.ph3.Marker = 'o';
                    app.optimPlot.ph3.MarkerSize = 7;
                    app.optimPlot.ph3.MarkerEdgeColor = [0 0.4470 0.7410];% nice blue
                    app.optimPlot.ph3.MarkerFaceColor = [0.3010 0.7450 0.9330];% nice light blue
                    hold(app.UIAxes_optimPlot,'off');
                    
                    % Show last point in optimplot as "selected"
                    optimPlotSelected(app, optimvalues.iteration, -1*optimvalues.fval)
                    
                    stop = true;
            end
            
            stop = app.optimPlot.stop;
            
            
            %if ~isempty(optimvalues.fval)
            if ~strcmp(state,'init')
                
                app.optimPlot.iteration = [app.optimPlot.iteration optimvalues.iteration] ;
                app.optimPlot.fval = [app.optimPlot.fval optimvalues.fval];
                
                app.optimPlot.ph.XData = app.optimPlot.iteration;
                app.optimPlot.ph.YData = -1*app.optimPlot.fval;
                refreshdata(app.optimPlot.ph);
                
                searchTime = toc(app.Session.startTime);
                
                app.UIAxes_optimPlot.Title.String = sprintf('Best score: %3.3f \t\t\t Elapsed time: %3.0f s', app.ZEALalignment.Score, searchTime);
                
                if optimvalues.fval < (app.Session.prev_val*1.001)
                    
                    app.Session.prev_val = optimvalues.fval;
                    
                    app.ZEALalignment.Score = -1*optimvalues.fval;
                    app.Session.searchTime = searchTime;
                    
                    updatePlot = true;
                    
                else
                    updatePlot = false;
                end
                
                if updatePlot || (strcmp(state,'done')) || (optimvalues.iteration == 1)
                    
                    app.optimPlot.updateCount = app.optimPlot.updateCount + 1;
                    
                    app.optimPlot.rot_history(app.optimPlot.updateCount,:) = x;
                    app.optimPlot.zealscore_history(app.optimPlot.updateCount,:) = -1*optimvalues.fval;
                    app.optimPlot.iteration_history(app.optimPlot.updateCount) = optimvalues.iteration;
                    
                    if app.Session.viewprogress
                        
                        updateResultLabel(app);
                        
                        JSmolRotate(app, x);
                        
                    end
                end
                
                if mod(optimvalues.iteration,app.Session.drawnowFreq)==1
                    drawnow limitrate;
                end
                
            end
        end
        
        function updateAfterSearch(app)
            
            updateResultLabel(app);
            
            app.JSmol.fix.shapeFunctionDataUpdate = true;
            app.JSmol.rot.shapeFunctionDataUpdate = true;
            
            app.JSmol.fix.shapeFunctionCommandUpdate = true;
            app.JSmol.rot.shapeFunctionCommandUpdate = true;
            
            updateAllShapes(app);
            
            app.SavePdbButton.Enable = true;
            
        end
        
        function updateAllShapes(app)
            
            app.ZEALalignment.Settings.molShape.FunctionType = getShapeFunctionSel(app);
            
            updateFixShape(app);
            
            updateRotShape(app);
            
            updateResultLabel(app);
            
            if app.ShapefunctionButton.Value
                ShapefunctionButtonValueChanged(app, matlab.ui.eventdata.ButtonPushedData);
            end
            
        end
        
        function ShapeFunctionType = getShapeFunctionSel(app)
            
            switch app.ShapeRepDropDown.Value
                case 1
                    switch str2double( app.molSurfDropDown.Value)
                        case 1
                            ShapeFunctionType = 'MS';
                        case 2
                            ShapeFunctionType = 'SAS';
                    end
                case 2
                    ShapeFunctionType = 'density';
            end
        end
        
        function updateFixShape(app)
            app.ZEALalignment.fixed.molShape.GridRes = app.ZEALalignment.Settings.molShape.GridRes;
            app.ZEALalignment.fixed.molShape.FunctionType = app.ZEALalignment.Settings.molShape.FunctionType;
            app.ZEALalignment.fixed.molShape.ProbeRadius = app.ZEALalignment.Settings.molShape.ProbeRadius;
            app.ZEALalignment.fixed.molShape.SmearFactor = app.ZEALalignment.Settings.molShape.SmearFactor;
            app.ZEALalignment.fixed.molShape.ShellThickness = app.ZEALalignment.Settings.molShape.ShellThickness;
            
            updateZEAL(app.ZEALalignment,'Structure','fixed','NewShape', true);
        end
        
        function updateRotShape(app)
            app.ZEALalignment.rotating.molShape.GridRes = app.ZEALalignment.Settings.GridRes;
            app.ZEALalignment.rotating.molShape.FunctionType = app.ZEALalignment.Settings.molShape.FunctionType;
            app.ZEALalignment.rotating.molShape.ProbeRadius = app.ZEALalignment.Settings.molShape.ProbeRadius;
            app.ZEALalignment.rotating.molShape.SmearFactor = app.ZEALalignment.Settings.molShape.SmearFactor;
            app.ZEALalignment.rotating.molShape.ShellThickness = app.ZEALalignment.Settings.molShape.ShellThickness;
            
            updateZEAL(app.ZEALalignment,'Structure','rotating','NewShape', true);
        end
        
        function startJSmol(app)
            warning off Matlab:structOnObject
            warning off MATLAB:ui:javaframe:PropertyToBeRemoved
            
            fprintf('\n --- Starting the JSmol app for ZEALalignment ---\n');
            
            if app.JSmol.webGLop
                %app.HTML.HTMLSource = fullfile(JSmolLibPath, 'jsmolWebGL_matlab_static.htm');
                
                app.HTML.HTMLSource = fullfile(app.paths.JSmol, 'jsmolWebGL_matlabDataTransfer.htm');
                %                 app.TypeDropDown_fix.Items = {'None','van der Waals surface','solvent-accessible surface'};
                %                 app.TransparencyDropDown_fix.Enable = false;
                %                 app.TransparencyDropDown_fix.Tooltip = 'Deactivate webGL to enable translucent surfaces; untick the webGL checkbox in ZEALalignment''s graphics options.';
                %
                %                 app.TypeDropDown_rot.Items = {'None','van der Waals surface','solvent-accessible surface'};
                %                 app.TransparencyDropDown_rot.Enable = false;
                %                 app.TransparencyDropDown_rot.Tooltip = 'Deactivate webGL to enable translucent surfaces; untick the webGL checkbox in ZEALalignment''s graphics options.';
                %
                fprintf('\n Using WebGL and HTML5\n');
            else
                app.HTML.HTMLSource = fullfile(app.paths.JSmol, 'jsmol_matlab_static.htm');
                %                 app.TypeDropDown_fix.Items = {'None','van der Waals surface','solvent-accessible surface','solvent-excluded surface'};
                %
                %                 app.TypeDropDown_rot.Items = {'None','van der Waals surface','solvent-accessible surface','solvent-excluded surface'};
                %
                fprintf('\n Using HTML5\n');
            end
            
            app.HTML.Data = ' ';
            
        end
        
        function [pdbData] = updatePDBdata(app, structureOption, updatePDBData)
            
            if strcmp(structureOption, 'fixed')
                if updatePDBData
                    % get new coordinates for fixed structure (should be same as original unless we have added new functionality to ZEAL)
                    pdbData = app.ZEALalignment.fixed.PDB.Data;
                    atomList = app.ZEALalignment.fixed.molShape.AtomData;
                else % get original coordinates
                    pdbData = app.ZEALfix.fixed.PDB.Data;
                    atomList = app.ZEALfix.fixed.molShape.AtomData;
                end
            elseif strcmp(structureOption,'rotating')
                if updatePDBData
                    % get new (rotated) coordinates for rotating structure
                    pdbData = app.ZEALalignment.rotating.PDB.Data;
                    atomList = app.ZEALalignment.rotating.molShape.AtomData;
                else % get original coordinates
                    pdbData = app.ZEALrot.fixed.PDB.Data;
                    atomList = app.ZEALrot.fixed.molShape.AtomData;
                end
            else
                
                error('Argument can only be be ''fixed'' or ''rotating'' ' );
                
            end
            
            for i = 1:size(atomList,1)
                pdbData.X(i) = atomList(i,1);
                pdbData.Y(i) = atomList(i,2);
                pdbData.Z(i) = atomList(i,3);
            end
            
        end
        
        function load2JSmol(app, varargin)
            
            p = inputParser();
            
            addOptional(p, 'Structure','all');
            addOptional(p, 'Update', false); % if true update PDB formated data to JSmol with rotated coordinates in ZEALalignment, otherwise get original coordinates
            addOptional(p, 'ReloadRepeat', true);
            
            parse(p, varargin{:});
            
            structureOption = p.Results.Structure;
            updateData = p.Results.Update;
            ReloadRepeat = p.Results.ReloadRepeat;
            
            % Set colors
            app.JSmol.fixStructure.color = round(app.Session.fixcolor*255);
            app.JSmol.rotStructure.color = round(app.Session.rotcolor*255);
            
            if strcmp(structureOption, 'fixed')
                
                if updateData
                    fixPDBdata = updatePDBdata(app, 'fixed',true);
                else
                    fixPDBdata = updatePDBdata(app, 'fixed',false);
                end
                fixJSmolSelStr = 'select 1.1';
                
                % create JSON
                pdbJSmolJSON = ZEAL.struct2JSON(fixPDBdata);
                
                % convert JSON to Matlab struct
                pdbJSmolMatStruct = jsondecode(pdbJSmolJSON);
                
            elseif strcmp(structureOption, 'rotating')
                
                if updateData
                    rotPDBdata = updatePDBdata(app, 'rotating', true);
                else
                    rotPDBdata = updatePDBdata(app, 'rotating', false);
                end
                
                rotJSmolSelStr = 'select 1.1';
                
                % create JSON
                pdbJSmolJSON = ZEAL.struct2JSON(rotPDBdata);
                
                % convert JSON to Matlab struct
                pdbJSmolMatStruct = jsondecode(pdbJSmolJSON);
                
            elseif strcmp(structureOption, 'all')
                
                if updateData
                    fixPDBdata = updatePDBdata(app, 'fixed',true);
                    rotPDBdata = updatePDBdata(app, 'rotating',true);
                else
                    fixPDBdata = updatePDBdata(app, 'fixed',false);
                    rotPDBdata = updatePDBdata(app, 'rotating',false);
                end
                
                fixJSmolSelStr = 'select 1.1';
                rotJSmolSelStr = 'select 1.2';
                
                % create JSON
                pdbJSmolJSON = ZEAL.struct2JSON(fixPDBdata, rotPDBdata);
                
                % convert JSON to Matlab struct
                pdbJSmolMatStruct = jsondecode(pdbJSmolJSON);
                
            end
            
            pdbJSmolMatStruct.flag = '1'; % trigger load event
            
            fprintf('\n Loading pdb data to JSmol for %s-', structureOption);
            app.HTML.Data = pdbJSmolMatStruct;
            
            fprintf('\n Waiting %2.1f s for JmolApplet to sync.', app.JSmol.pauseTime);
            pause(app.JSmol.pauseTime); % pause is needed for JSmol to be updated properly until next change
            
            
            enableJSmolMolMode(app);
            
            app.CartoonButton.Value = true;
            app.BallandstickButton.Value = false;
            app.sasurfaceButton.Value = false;
            app.SpacefillButton.Value = false;
            
            %  --- Configure visualization styles in JSmol ---
            if strcmp(structureOption, 'all')
                if app.CartoonButton.Value
                    % Set commands to JSmol
                    scriptStr = sprintf(['set antialiasDisplay ON; set antialiasTranslucent ON; set translucent %s;', ...
                        'background {0.94, 0.94, 0.94}; select 1.1, 1.2; cartoon ON; cpk off; wireframe off; frame all;', ...
                        '%s; color [%d %d %d]; %s; color [%d %d %d];'], ...
                        app.JSmol.trueTrans, ...
                        fixJSmolSelStr, app.JSmol.fixStructure.color(1), app.JSmol.fixStructure.color(2), app.JSmol.fixStructure.color(3), ...
                        rotJSmolSelStr, app.JSmol.rotStructure.color(1), app.JSmol.rotStructure.color(2), app.JSmol.rotStructure.color(3));
                    
                end
                
                nAtoms = length(app.ZEALfix.fixed.PDB.Data.X) + length(app.ZEALrot.fixed.PDB.Data.X);
                
            elseif strcmp(structureOption, 'fixed')
                
                if app.CartoonButton.Value
                    % Set commands to JSmol
                    scriptStr = sprintf(['set antialiasDisplay ON; set antialiasTranslucent ON; set translucent %s;', ...
                        'background {0.94, 0.94, 0.94}; select 1.1; cartoon ON; cpk off; wireframe off;', ...
                        'select 1.1; color [%d %d %d];'], ...
                        app.JSmol.trueTrans, ...
                        app.JSmol.fixStructure.color(1), app.JSmol.fixStructure.color(2), app.JSmol.fixStructure.color(3));
                    
                end
                
                nAtoms = length(app.ZEALfix.fixed.PDB.Data.X);
                
            elseif strcmp(structureOption, 'rotating')
                
                if app.CartoonButton.Value
                    % Set commands to JSmol
                    scriptStr = sprintf(['set antialiasDisplay ON; set antialiasTranslucent ON; set translucent %s;', ...
                        'background {0.94, 0.94, 0.94}; select 1.1; cartoon ON; cpk off; wireframe off; frame all;', ...
                        'select 1.1; color [%d %d %d];'], ...
                        app.JSmol.trueTrans, ...
                        app.JSmol.rotStructure.color(1), app.JSmol.rotStructure.color(2), app.JSmol.rotStructure.color(3));
                    
                end
                
                nAtoms = length(app.ZEALrot.fixed.PDB.Data.X);
            end
            
%             %% Query if structure loaded to JSmol started
%             nSel = 0;
%             pauseCount = 0;
%             
%             fprintf('\n Checking if structure loaded in JSmol ... ');
%             while nSel ~= nAtoms
%                 pdbJSmolMatStruct.flag = "3"; % -> select all in JSmol and get back data of the selected atoms (atominfo property)
%                 pdbJSmolMatStruct.pdb=[];
%                 pdbJSmolMatStruct.scriptStr=[];
%                 
%                 app.HTML.Data = pdbJSmolMatStruct;
%                 pauseCount = pauseCount + 1;
%                 pause(1);
%                 
%                 nSel = length(app.HTML.Data);
%                 
%                 if pauseCount > 5 && ReloadRepeat
%                     
%                     fprintf('\n No atominfo could be fetched from JSmol. Reloading JSmol');
%                     
%                     
%                     load2JSmol(app, 'Structure', structureOption, 'Update', updateData, 'ReloadRepeat', false)
%                     
%                 end
%                 
%             end
%             
%             fprintf('OK!');
            fprintf('\n Sending JSmol script command:\n%s', scriptStr);
            % Send script command
            pause(app.JSmol.pauseTime);
            pdbJSmolMatStruct.flag = '2'; % trigger JSmol script
            pdbJSmolMatStruct.scriptStr = scriptStr;
            app.HTML.Data = pdbJSmolMatStruct;
            pause(app.JSmol.pauseTime);
            
        end
        
        function JSmolRotate(app, EulerAngles_ZYZ)
            
            app.optimPlot.quatZYZ_old = app.optimPlot.quatZYZ_new;
            
            % Get quaterions for rotations in JSmol
            app.optimPlot.quatZYZ_new = eul2quat(EulerAngles_ZYZ,'ZYZ');
            
            % reverse old rotation = the conjugate of the old
            % quaternion
            revOldQuat = quatconj(app.optimPlot.quatZYZ_old);
            
            % create quaterion for the composite rotation
            compositeQuat = quatmultiply(revOldQuat, app.optimPlot.quatZYZ_new);
            
            % Perform rotation in JSmol
            scriptStr = sprintf('select 1.2; rotate selected MOLECULAR QUATERNION %5.5f %5.5f %5.5f %5.5f', -1*compositeQuat(2), -1*compositeQuat(3), -1*compositeQuat(4), compositeQuat(1));
            
            %             if app.ShapefunctionButton.Value
            %
            %                 shapeFunRotateCommand = sprintf('isosurface rot_dx rotate {%5.5f %5.5f %5.5f %5.5f}',  -1*compositeQuat(2), -1*compositeQuat(3), -1*compositeQuat(4), compositeQuat(1));
            %                 scriptStr = [scriptStr ';' shapeFunRotateCommand];
            %
            %             end
            
            pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
            pdbJSmolMatStruct.scriptStr = scriptStr;
            app.HTML.Data = pdbJSmolMatStruct;
            pause(0.1);
            
            %             if app.sasurfaceButton.Value
            %                 showSurfaceJSmol(app);
            %             end
            
        end
        
        function disableHistory(app)
            
            app.originalButton.Enable = false;
            app.finalButton.Enable = false;
            app.previousButton.Enable = false;
            app.nextButton.Enable = false;
            app.resetButton.Enable = false;
            
            msg = 'Perform search or click "Molecules" view in JSmol tab to enable' ;
            app.originalButton.Tooltip = msg;
            app.finalButton.Tooltip = msg;
            app.previousButton.Tooltip = msg;
            app.nextButton.Tooltip = msg;
            app.resetButton.Tooltip = msg;
            
        end
        
        function enableHistory(app)
            
            app.originalButton.Enable = true;
            app.finalButton.Enable = true;
            app.previousButton.Enable = true;
            app.nextButton.Enable = true;
            app.resetButton.Enable = true;
            
            app.originalButton.Tooltip = 'Show original orientation';
            app.finalButton.Tooltip = 'Show final orientation after search';
            app.previousButton.Tooltip = 'Show previous orientation during search';
            app.nextButton.Tooltip = 'Show next orientation during search';
            app.resetButton.Tooltip = 'Reset to state before latest search';
            
        end
        
        function updateBeforeSearch(app)
            
            resetOptimPlot(app);
            
            
            app.optimPlot.solCount = 1;
            app.optimPlot.nSolutions = 1;
            
        end
        
        function resetSearch(app)
            
            app.ZEALalignment = mergeZEAL(app.ZEALalignment,app.ZEALfix, app.ZEALrot);
            
            resetOptimPlot(app);
            
            app.optimPlot.solCount = 1;
            app.optimPlot.nSolutions = 1;
            
            load2JSmol(app, 'Structure','all', 'Update', true);
            
        end
        
        function optimPlotSelected(app, iteration, zealscore)
            % plot selected point in optimPlot
            if isprop(app.optimPlot.ph2,'Color')
                delete(app.optimPlot.ph2);
            end
            
            hold(app.UIAxes_optimPlot,'on');
            
            app.optimPlot.ph2 = plot(app.UIAxes_optimPlot, iteration, zealscore,'o');
            app.optimPlot.ph2.Marker = 'o';
            app.optimPlot.ph2.MarkerSize = 7;
            app.optimPlot.ph2.MarkerEdgeColor = [0 0.4470 0.7410];% nice "ZEALalignment" blue
            app.optimPlot.ph2.MarkerFaceColor = [0.9290 0.6940 0.1250];% nice yellow
            
            hold(app.UIAxes_optimPlot,'off');
        end
        
        function resetOptimPlot(app)
            
            app.optimPlot.reset = true;
            
            try
                delete(app.optimPlot.ph);
                delete(app.optimPlot.ph2);
                delete(app.optimPlot.ph3);
                
                %app.optimPlot
                
                app.UIAxes_optimPlot.Title.String = '';
                
                app.optimPlot.rot_history = [];
                app.optimPlot.zealscore_history = [];
                app.optimPlot.iteration_history = [];
                
            catch
                % no optimplot created
            end
            
            disableHistory(app);
            
        end
        
        function showSurfaceJSmol(app)
            
            scriptStr = '';
            
            if app.Session.fix.loaded
                scriptStr = [scriptStr app.Session.fix.JSmol.surfStr ';' app.Session.fix.JSmol.colStr '; display $fixSurf;'];
            end
            
            if app.Session.rot.loaded
                scriptStr = [scriptStr ';' app.Session.rot.JSmol.surfStr ';' app.Session.rot.JSmol.colStr '; display $rotSurf;'];
            end
            
            pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
            pdbJSmolMatStruct.scriptStr = scriptStr;
            
            fprintf('\nSending JSmol script command:\n%s', scriptStr);
            
            app.HTML.Data = pdbJSmolMatStruct;
            pause(0.1);
            
        end
        
        function JSmol_sasurfaceONOFF(app)
            
            pdbJSmolMatStruct.flag = '2'; % trigger JSmol script
            selStr = getJSmolSelectStr(app);
            
            %             if ~app.TranslucentButton.Value
            %
            %                 if app.CartoonButton.Value
            %                     app.CartoonButton.Value = false;
            %
            %                     scriptStr = sprintf('select %s; cartoon off;', selStr);
            %                     pdbJSmolMatStruct.scriptStr = scriptStr;
            %                     app.HTML.Data = pdbJSmolMatStruct;
            %                     pause(0.1);
            %                 end
            %
            %                 if app.SpacefillButton.Value || app.BallandstickButton.Value
            %                     app.SpacefillButton.Value = false;
            %
            %                     scriptStr = sprintf('select %s; wireframe off; backbone off; wireframe off; spacefill off', selStr);
            %                     pdbJSmolMatStruct.scriptStr = scriptStr;
            %                     app.HTML.Data = pdbJSmolMatStruct;
            %                     pause(0.1);
            %                 end
            %
            %                 if app.BallandstickButton.Value
            %                     app.BallandstickButton.Value = false;
            %                     scriptStr = sprintf('select %s; cpk 20%; backbone off; spacefill 20%; wireframe 0.15', selStr);
            %                     pdbJSmolMatStruct.scriptStr = scriptStr;
            %                     app.HTML.Data = pdbJSmolMatStruct;
            %                     pause(0.1);
            %                 end
            %
            %             end
            
            if app.MolecularsurfaceButton.Value
                scriptStr = 'select all; isosurface off; isosurface delete';
                pdbJSmolMatStruct.scriptStr = scriptStr;
                app.HTML.Data = pdbJSmolMatStruct;
                pause(0.1);
            end
            
            % Define RGB codes in JSmol
            app.JSmol.fixStructure.color = round(app.Session.fixcolor*255);
            app.JSmol.rotStructure.color = round(app.Session.rotcolor*255);
            
            resolution = app.ResolutionDropDown.Value;
            
            if app.Session.fix.loaded || app.Session.rot.loaded
                fixSelStr = '1.1';
                rotSelStr = '1.1';
                fixIgnoreStr = '1.2';
                rotIgnoreStr = '1.2';
            end
            
            if app.Session.fix.loaded && app.Session.rot.loaded
                fixSelStr = '1.1';
                rotSelStr = '1.2';
                fixIgnoreStr = '1.2';
                rotIgnoreStr = '1.1';
            end
            
            app.Session.fix.JSmol.surfStr = sprintf('isosurface fixSurf model %s connect {%s} resolution %s select (%s) ignore (%s) sasurface;', fixSelStr, fixSelStr, resolution, fixSelStr, fixIgnoreStr);
            app.Session.fix.JSmol.colStr = sprintf('color $fixSurf [%d %d %d]', app.JSmol.fixStructure.color(1), app.JSmol.fixStructure.color(2), app.JSmol.fixStructure.color(3));
            
            if app.TranslucentButton.Value
                app.Session.fix.JSmol.colStr = [app.Session.fix.JSmol.colStr '; color isosurface translucent'];
            end
            
            app.Session.rot.JSmol.surfStr = sprintf('isosurface rotSurf model %s connect {%s} resolution %s select (%s) ignore (%s) sasurface;', rotSelStr, rotSelStr, resolution, rotSelStr, rotIgnoreStr);
            app.Session.rot.JSmol.colStr = sprintf('color $rotSurf [%d %d %d]', app.JSmol.rotStructure.color(1), app.JSmol.rotStructure.color(2), app.JSmol.rotStructure.color(3));
            
            if app.TranslucentButton.Value
                app.Session.rot.JSmol.colStr = [app.Session.rot.JSmol.colStr '; color isosurface translucent'];
            end
            
            if app.sasurfaceButton.Value
                showSurfaceJSmol(app);
            else
                scriptStr = 'select all; isosurface off; isosurface delete';
                pdbJSmolMatStruct.scriptStr = scriptStr;
                app.HTML.Data = pdbJSmolMatStruct;
                pause(0.1);
            end
        end
        
        function JSmol_molsurfaceONOFF(app)
            
            pdbJSmolMatStruct.flag = '2'; % trigger JSmol script
            
            if ~app.TranslucentButton.Value
                if app.CartoonButton.Value
                    app.CartoonButton.Value = false;
                    
                    scriptStr = 'select all; cartoon off;';
                    pdbJSmolMatStruct.scriptStr = scriptStr;
                    app.HTML.Data = pdbJSmolMatStruct;
                    pause(0.1);
                end
                
                if app.SpacefillButton.Value || app.BallandstickButton.Value
                    app.SpacefillButton.Value = false;
                    
                    scriptStr = 'select all; wireframe off; backbone off;wireframe off;spacefill off';
                    pdbJSmolMatStruct.scriptStr = scriptStr;
                    app.HTML.Data = pdbJSmolMatStruct;
                    pause(0.1);
                end
                
                if app.BallandstickButton.Value
                    app.BallandstickButton.Value = false;
                    scriptStr = 'select all; cpk 20%; backbone off; spacefill 20%; wireframe 0.15';
                    pdbJSmolMatStruct.scriptStr = scriptStr;
                    app.HTML.Data = pdbJSmolMatStruct;
                    pause(0.1);
                end
                
            end
            
            if app.sasurfaceButton.Value
                scriptStr = 'select all; isosurface off; isosurface delete';
                pdbJSmolMatStruct.scriptStr = scriptStr;
                app.HTML.Data = pdbJSmolMatStruct;
                pause(0.1);
            end
            
            % Define RGB codes in JSmol
            app.JSmol.fixStructure.color = round(app.Session.fixcolor*255);
            app.JSmol.rotStructure.color = round(app.Session.rotcolor*255);
            
            resolution = app.ResolutionDropDown.Value;
            
            app.Session.fix.JSmol.surfStr = sprintf('isosurface fixSurf resolution %s select (1.1) ignore (1.2) molecular;', resolution);
            app.Session.fix.JSmol.colStr = sprintf('color $fixSurf [%d %d %d]', app.JSmol.fixStructure.color(1), app.JSmol.fixStructure.color(2), app.JSmol.fixStructure.color(3));
            
            if app.TranslucentButton.Value
                app.Session.fix.JSmol.colStr = [app.Session.fix.JSmol.colStr '; color isosurface translucent'];
            end
            
            app.Session.rot.JSmol.surfStr = sprintf('isosurface rotSurf resolution %s select (1.2) ignore (1.1) molecular;', resolution);
            app.Session.rot.JSmol.colStr = sprintf('color $rotSurf [%d %d %d]', app.JSmol.rotStructure.color(1), app.JSmol.rotStructure.color(2), app.JSmol.rotStructure.color(3));
            
            if app.TranslucentButton.Value
                app.Session.rot.JSmol.colStr = [app.Session.rot.JSmol.colStr '; color isosurface translucent'];
            end
            
            if app.MolecularsurfaceButton.Value
                showSurfaceJSmol(app);
            else
                scriptStr = 'select all; isosurface off; isosurface delete';
                pdbJSmolMatStruct.scriptStr = scriptStr;
                app.HTML.Data = pdbJSmolMatStruct;
                pause(0.1);
            end
            
        end
        
        
        function JSmol_cartoonONOFF(app, selStr)
            
            pdbJSmolMatStruct.flag = '2'; % trigger JSmol script
            
            if ~app.TranslucentButton.Value
                %                 if app.sasurfaceButton.Value || app.MolecularsurfaceButton.Value
                %                     app.sasurfaceButton.Value = false;
                %                     app.MolecularsurfaceButton.Value = false;
                %
                %                     scriptStr = sprintf('select %s; isosurface off', selStr);
                %                     pdbJSmolMatStruct.scriptStr = scriptStr;
                %                     app.HTML.Data = pdbJSmolMatStruct;
                %                     pause(0.1);
                %                 end
                
                if app.SpacefillButton.Value
                    app.SpacefillButton.Value = false;
                    
                    scriptStr = sprintf('select %s; wireframe off; backbone off; wireframe off; spacefill off', selStr);
                    pdbJSmolMatStruct.scriptStr = scriptStr;
                    app.HTML.Data = pdbJSmolMatStruct;
                    pause(0.1);
                end
            end
            
            if app.CartoonButton.Value %|| app.sasurfaceButton.Value
                scriptStr = sprintf('select %s; cartoon on', selStr);
            else
                scriptStr = sprintf('select %s; cartoon off', selStr);
            end
            
            
            fprintf('\n Sending JSmol script command:\n%s', scriptStr);
            pdbJSmolMatStruct.scriptStr = scriptStr;
            app.HTML.Data = pdbJSmolMatStruct;
        end
        
        
        function selStr = getJSmolSelectStr(app)
            
            if app.Session.fix.loaded || app.Session.rot.loaded
                selStr = '1.1';
            end
            
            if app.Session.fix.loaded && app.Session.rot.loaded
                selStr = 'all';
            end
            
        end
        
        function enableJSmolMolMode(app)
            
            app.FixedButton.Visible = false;
            app.RotatingButton.Visible = false;
            
            app.MoleculesButton.Value = true;
            app.ShapefunctionButton.Value = false;
            
            app.CartoonButton.Visible = true;
            app.sasurfaceButton.Visible = true;
            app.BallandstickButton.Visible = true;
            app.MolecularsurfaceButton.Visible = true;
            app.SpacefillButton.Visible = true;
            
            
        end
        
        function enableJSmolShapeFunMode(app)
            
            app.FixedButton.Visible = true;
            app.RotatingButton.Visible = true;
            
            app.MoleculesButton.Value = false;
            app.ShapefunctionButton.Value = true;
            
            app.CartoonButton.Visible = false;
            app.sasurfaceButton.Visible = false;
            app.BallandstickButton.Visible = false;
            app.MolecularsurfaceButton.Visible = false;
            app.SpacefillButton.Visible = false;
            
            if app.Session.fix.loaded
                app.FixedButton.Value = true;
                app.FixedButton.Enable = true;
            else
                app.FixedButton.Value = false;
                app.FixedButton.Enable = false;
            end
            
            if app.Session.rot.loaded
                app.RotatingButton.Value = true;
                app.RotatingButton.Enable = true;
            else
                app.RotatingButton.Value = false;
                app.RotatingButton.Enable = false;
            end
            
        end
        
        function  setShapeFunctionUpdateFlags(app, TF)
            
            app.JSmol.fix.shapeFunctionDataUpdate = TF;
            app.JSmol.rot.shapeFunctionDataUpdate = TF;
            
            app.JSmol.fix.shapeFunctionCommandUpdate = TF;
            app.JSmol.rot.shapeFunctionCommandUpdate = TF;
            
        end
        
        function updateJSmol_OptimPlotHistory(app)
            
            x = app.optimPlot.rot_history(app.optimPlot.solCount, :);
            
            % 1) compute rotation matrix to get from original to new
            % coordinates
            
            % - rotation matrix from previous to current state
            Rmat = ZEAL.euler2rotMat(x);
            XYZ_new = app.optimPlot.rot.AtomData(:,1:3) * Rmat;
            XYZ_orig = [app.ZEALalignment.rotating.PDB.Data.X app.ZEALalignment.rotating.PDB.Data.Y app.ZEALalignment.rotating.PDB.Data.Z];
            % - rotation matric from original to current state
            Rmat_fromOriginal = ZEAL.findRotation(XYZ_new',XYZ_orig');
            
            % 2) update coordinates by rotating the original coordinates
            app.ZEALalignment.rotating.molShape.AtomData(:,1:3) = app.ZEALrot.fixed.molShape.AtomData(:,1:3) * Rmat_fromOriginal;
            
            app.JSmol.rot.shapeFunctionDataUpdate = true;
            
            iteration = app.optimPlot.iteration_history(app.optimPlot.solCount);
            
            zealscore = app.optimPlot.zealscore_history(app.optimPlot.solCount, :);
            app.ZEALalignment.Score = zealscore;
            
            updateResultLabel(app);
            
            JSmolRotate(app, x);
            
            optimPlotSelected(app, iteration, zealscore)
            
            setShapeFunctionUpdateFlags(app, true);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            drawnow;
            app.ZEALwebUIFiguree.WindowState = 'maximized';
            
            warning off Matlab:structOnObject
            
            %% Setup paths
            if ~isdeployed
                
                app.paths.extPath = fullfile(pwd,'ext');
                app.paths.srcPath = fullfile(pwd,'src');
                
                app.paths.JSmol = fullfile(app.paths.extPath, 'jsmol');
                
                app.paths.tmp =  fullfile(pwd,'tmp');
                app.paths.chiCoeffs = fullfile(app.paths.srcPath, 'chi_coefficients');
                app.paths.images = fullfile(app.paths.srcPath,'images');
                app.paths.icons = fullfile(app.paths.images,'icons');
                app.paths.samples = fullfile(pwd,'sample_data');
                
            else
                
                app.paths.extPath = fullfile('/ext/');
                app.paths.srcPath = fullfile(pwd,'/src/');
                
                app.paths.JSmol = fullfile(app.paths.extPath, 'jsmol');
                
                app.paths.tmp =  fullfile('tmp');
                app.paths.chiCoeffs = fullfile(app.paths.srcPath, 'chi_coefficients');
                app.paths.images = fullfile(app.paths.srcPath,'images');
                app.paths.icons = fullfile(app.paths.images,'icons');
                app.paths.ROIpdbpath = app.paths.tmp;
                app.paths.samples = fullfile('sample_data');
                
            end
            
            % Get user home directory path
            app.paths.userHomeDir = getenv('HOME');
            
            if isempty(app.paths.userHomeDir)
                % assume windows machine
                app.paths.userHomeDir = getenv('HOMEPATH');
            end
            
            app.Session.fix.path = app.paths.userHomeDir;
            app.Session.rot.path = app.paths.userHomeDir;
            
            % ----------------- GUI SETUP -----------------
            
            disableSetupTab(app)
            
            app.fixBrowseButton.Visible = false;
            app.rotBrowseButton.Visible = false;
            
            % Surface representation
            app.ShapeRepDropDown.ItemsData = [1 2];
            app.ShapeRepDropDown.Value = 1;
            
            switch app.ShapeRepDropDown.Value
                case 1
                    app.molSurfDropDown.Visible = true;
                    app.probeRadiusSpinner.Visible = true;
                    app.ProberadiusSpinnerLabel.Visible = true;
                    app.shellThicknessLabel.Visible = true;
                    app.shellThicknessSpinner.Visible = true;
                    
                    app.SmearfactorEditField.Visible = false;
                    app.SmearfactorEditFieldLabel.Visible = false;
                    app.RelativeisovalueEditField.Visible = false;
                    app.RelativeisovalueEditFieldLabel.Visible = false;
                    
                    app.molSurfDropDown.Enable = false;
                    app.probeRadiusSpinner.Enable = false;
                    app.ProberadiusSpinnerLabel.Enable = false;
                    app.shellThicknessLabel.Enable = false;
                    app.shellThicknessSpinner.Enable = false;
                    
                case 2
                    app.SmearfactorEditField.Visible = true;
                    app.SmearfactorEditFieldLabel.Visible = true;
                    app.RelativeisovalueEditField.Visible = true;
                    app.RelativeisovalueEditFieldLabel.Visible = true;
                    
                    app.SmearfactorEditField.Enable = false;
                    app.SmearfactorEditFieldLabel.Enable = false;
                    app.RelativeisovalueEditField.Enable = false;
                    app.RelativeisovalueEditFieldLabel.Enable = false;
                    
                    app.molSurfDropDown.Visible = false;
                    app.probeRadiusSpinner.Visible = false;
                    app.ProberadiusSpinnerLabel.Visible = false;
                    app.shellThicknessLabel.Visible = false;
                    app.shellThicknessSpinner.Visible = false;
            end
            
            app.EmailaddressEditField.Visible = false;
            app.EmailaddressEditFieldLabel.Visible = false;
            
            disableSearch(app);
            
            % --------------------------------------------
            
            % Session
            app.Session.updaterot = false;
            app.Session.updatefix = false;
            app.Session.ZEALscore = NaN;
            
            app.Session.fix.loaded = false;
            app.Session.rot.loaded = false;
            
            app.Session.fix.name = '';
            app.Session.rot.name = '';
            
            app.Session.fixcolor = [0.8500    0.3250    0.0980];
            app.Session.rotcolor = [0    0.4470    0.7410];
            
            app.Session.Search.History.SearchSaveFactor = 1.01;
            
            app.Session.viewprogress = true;
            
            app.Session.drawnowFreq = 5; % controls how often UIAxes updates during surropt search
            
            % Demo mode
            app.demo.waitTime = 0.3; %s
            app.demo.fixPDB = '5MOK';
            app.demo.fixPDBchain = 'A';
            app.demo.rotPDB = '2HO1';
            app.demo.rotPDBchain = 'A';
            
            app.demo.loadDemo = false;
            
            app.ZEALsettings.Order = str2double(app.ZCmomentorderDropDown.Value);
            app.ZEALsettings.GridRes = app.GridresolutionEditField.Value;
            app.ZEALsettings.ShapeFunction = 'MS';
            app.ZEALsettings.ProbeRadius = app.probeRadiusSpinner.Value;
            app.ZEALsettings.ShellThickness = app.shellThicknessSpinner.Value;
            app.ZEALsettings.SmearFactor = app.SmearfactorEditField.Value;
            app.ZEALsettings.FunEvals = app.FunctionevaluationsEditField.Value;
            
            
            % Create empty ZEAL object and assign parameters
            app.ZEALalignment = ZEAL(app.ZEALsettings);
            
            app.ZEALalignment.Settings.SurrOpt.OutputFcn = @app.surrOptiOutputFcn;
            
            
            % optim plot figure
            app.UIAxes_optimPlot.XLim = [0 app.ZEALalignment.Settings.SurrOpt.MaxFunctionEvaluations];
            
            app.optimPlot.ph = [];
            app.optimPlot.ph2 = [];
            
            app.optimPlot.reset = true;
            app.optimPlot.solCount = 1;
            app.optimPlot.nSolutions = 1;
            app.optimPlot.plotcolor = [0    0.4470    0.7410];
            
            % JSmol settings
            app.TabGroup2.SelectedTab = app.JSMOLTab;
            
            app.JSmol.webGL = true;
            app.JSmol.trueTrans = 'false';
            
            app.JSmol.rotSaved = false;
            app.JSmol.fixSaved = false;
            
            app.JSmol.pauseTime = 1; % seconds to wait for JSmol to initiate until sending commands
            app.JSmol.webGLop = true;
            startJSmol(app);
            
            app.JSmol.fix.shapeFunctionDataUpdate = true;
            app.JSmol.rot.shapeFunctionDataUpdate = true;
            
            app.JSmol.fix.shapeFunctionCommandUpdate = true;
            app.JSmol.rot.shapeFunctionCommandUpdate = true;
            
            app.JSmol.fix.shapeFunctionIsoVal = 0.5;
            app.JSmol.rot.shapeFunctionIsoVal = 0.5;
            
            
        end

        % Button pushed function: importFix
        function importFixButtonPushed(app, event)
            
            % LOAD MODE: fetch from pdb or upload pdb file locally
            loadMode = app.fixSwitch.Value; % 'LOAD' or 'FETCH'
            
            % CHAIN SELECTION
            chainIDsel = app.fixCHAINIDEditField.Value;
            
            if isempty(chainIDsel) % all chains will be imported from pdb fata
                chainStr = '';
            else % selected chain will be extracted from imported pdb data
                chainStr = sprintf(' (chain %s)', chainIDsel);
            end
            
            if isempty(chainIDsel)
                app.ZEALsettings.fix_chainID = 'all';
            else
                app.ZEALsettings.fix_chainID = chainIDsel;
            end
            
            if app.Session.fix.loaded % structure loaded, remove all data associated with it
                resetFix(app);
            else
                
                %if ~app.demo.loadDemo
                % ---------------- LOAD FROM FILE ----------------
                if strcmp(loadMode,'LOAD')
                    
                    if isempty(app.Session.fix.name)
                        warningMsg = sprintf('No file selected.');
                        uialert(app.ZEALwebUIFiguree, warningMsg, 'Warning', 'icon', 'warning');
                        return
                    else
                        % <<<<<<<<< Start progressbar
                        MeStr = sprintf('Loading %s%s', app.Session.fix.name, chainStr);
                        d = uiprogressdlg(app.ZEALwebUIFiguree,'Title','Please Wait', 'Indeterminate','on','Message',MeStr);
                        
                        % Create ZEALalignment object for fixed
                        structureFile = fullfile(app.Session.fix.path, app.Session.fix.name);
                        try
                            app.ZEALfix = ZEAL('fix',structureFile, app.ZEALsettings);
                            foundFileTF = true;
                        catch ME
                            foundFileTF = false;
                            warning(ME.message);
                        end
                        close(d);% progressbar >>>>>>>>>
                        
                        if ~foundFileTF
                            warningMsg = sprintf('Could not find file. Downloaded structure %s from RCSB PDB instead', app.Session.fix.name(1:4));
                            uialert(app.ZEALwebUIFiguree, warningMsg, 'Warning', 'icon', 'warning');
                        end
                        
                        if isempty(app.ZEALfix.fixed.PDB.Data.X)
                            errorStr = sprintf('No atom records for chain ID "%s" found in %s', chainIDsel, ZEALfixname);
                            uialert(app.ZEALwebUIFiguree, errorStr, 'Loading error','icon', 'error');
                            return;
                        end
                        
                    end
                    
                    
                else % ---------------- DOWNLOAD FROM PDB ----------------
                    
                    inputOK = checkFixPDBIDinput(app);
                    
                    if inputOK
                        
                        pdbidSel = app.fixPDBIDEditField.Value;
                        
                        
                        MeStr = sprintf('Downloading %s%s from PDB', upper(pdbidSel), chainStr);
                        
                        % <<<<<<<<< Start progressbar
                        d = uiprogressdlg(app.ZEALwebUIFiguree,'Title','Please Wait', 'Indeterminate','on','Message',MeStr);
                        
                        % Create ZEALalignment object for fixed
                        try
                            app.ZEALfix = ZEAL('fix',[pdbidSel chainIDsel], app.ZEALsettings);
                            downloadedFileTF = true;
                        catch ME
                            downloadedFileTF = false;
                            warning(ME.message);
                        end
                        
                        close(d);% progressbar >>>>>>>>>
                        
                        
                        % Check download and data OK
                        if ~downloadedFileTF
                            errorMsg = sprintf('Could not download structure %s from RCSB PDB', pdbidSel);
                            uialert(app.ZEALwebUIFiguree, errorMsg, 'Loading error','icon', 'error');
                            return;
                        end
                        
                        if isempty(app.ZEALfix.fixed.PDB.Data.X)
                            errorStr = sprintf('No atom records found for PDB ID "%s" and chain ID "%s"', pdbidSel, chainIDsel);
                            uialert(app.ZEALwebUIFiguree, errorStr, 'Loading error','icon', 'error');
                            return;
                        end
                    else
                        return;
                    end
                end
                
                app.Session.fix.loaded = true;
                
                %end
                
                
                % ---------------- update GUI ----------------
                app.importFix.BackgroundColor = [0.94 0.94 0.94];
                
                if isdeployed
                    app.importFix.Icon = 'icon-mat-delete-0072bd.png';
                else
                    app.importFix.Icon = fullfile(app.paths.icons, 'icon-mat-delete-0072bd.png');
                end
                
                app.importFix.Text = 'Delete';
                app.importFix.Tooltip = 'Reset and delete fixed structure.';
                
                app.fixCHAINIDEditField.BackgroundColor = app.Session.fixcolor;
                app.fixCHAINIDEditField.FontColor = [1 1 1];
                app.fixCHAINIDEditField.FontWeight = 'bold';
                
                app.fixPDBIDEditField.BackgroundColor = app.Session.fixcolor;
                app.fixPDBIDEditField.FontColor = [1 1 1];
                app.fixPDBIDEditField.FontWeight = 'bold';
                
                app.fixBrowseButton.Enable = false;
                
                % ---------------- Allow shape ZEALalignment if two structures imported ----------------
                if app.Session.rot.loaded
                    
                    % merge ZEAL single mode objects
                    app.ZEALalignment = mergeZEAL(app.ZEALalignment, app.ZEALfix, app.ZEALrot);
                    
                    %computeMomentsZEALscore(app);
                    enableSearch(app);
                    enableSetupTab(app);
                    updateResultLabel(app);
                    load2JSmol(app, 'Structure','all', 'Update', true);
                else
                    load2JSmol(app,'Structure','fixed');
                end
                
                
            end
            
        end

        % Value changed function: fixSwitch
        function fixSwitchValueChanged(app, event)
            
            loadmode = app.fixSwitch.Value;
            resetFix(app);
            
            if strcmp(loadmode,'LOAD')
                
                app.fixPDBIDEditFieldLabel.Text = 'Filename';
                app.importFix.Tooltip = 'Upload pdb file for fixed structure.';
                if isdeployed
                    app.importFix.Icon = 'icon-mat-file-upload-0072bd.png';
                else
                    app.importFix.Icon = fullfile(app.paths.icons, 'icon-mat-file-upload-0072bd.png');
                end
                
                app.fixPDBIDEditField.Value = 'No file selected';
                app.fixBrowseButton.Visible = true;
                app.fixBrowseButton.Enable = true;
                
                app.importFix.Text = 'Upload';
                %app.importFix.Enable = false;
                
            else
                
                app.fixPDBIDEditFieldLabel.Text = 'PDB ID code';
                app.fixPDBIDEditFieldLabel.Visible = true;
                
                app.importFix.Tooltip = 'Download fixed structure from RCSB PDB.';
                
                if isdeployed
                    app.importFix.Icon = 'icon-mat-file-download-0072bd.png';
                else
                    app.importFix.Icon = fullfile(app.paths.icons, 'icon-mat-file-download-0072bd.png');
                end
                
                app.fixPDBIDEditField.Value = '';
                
                app.fixBrowseButton.Visible = false;
                
                app.importFix.Text = 'Download';
                
            end
            
        end

        % Button pushed function: fixBrowseButton
        function fixBrowseButtonPushed(app, event)

            if ~isdeployed
                app.ZEALwebUIFiguree.Visible = 'off';
            end
            
            [app.Session.fix.name, app.Session.fix.path] = uigetfile({'*.pdb','Data Files (*.pdb)';'*.*','All Files (*.*)'},'Load pdb file ',app.Session.fix.path);
            
            if isequal(app.Session.fix.name,0) || isequal(app.Session.fix.path,0)
                fprintf('\nUser pressed cancel')
                
            else
                app.Session.rot.path = app.Session.fix.path;
                app.ZEALwebUIFiguree.Visible = 'on';
                
                % Show fileanme in "PDB ID field"
                app.fixPDBIDEditField.Value = app.Session.fix.name;
                
            end
            
            if ~isdeployed
                app.ZEALwebUIFiguree.Visible = 'on';
            end
            
        end

        % Button pushed function: importRot
        function importRotButtonPushed(app, event)
            
            % LOAD MODE: fetch from pdb or upload pdb file locally
            loadMode = app.rotSwitch.Value; % 'LOAD' or 'FETCH'
            
            % CHAIN SELECTION
            chainIDsel = app.rotCHAINIDEditField.Value;
            
            if isempty(chainIDsel) % all chains will be imported from pdb fata
                chainStr = '';
            else % selected chain will be extracted from imported pdb data
                chainStr = sprintf(' (chain %s)', chainIDsel);
            end
            
            if isempty(chainIDsel)
                app.ZEALsettings.fix_chainID = 'all';
            else
                app.ZEALsettings.fix_chainID = chainIDsel;
            end
            
            if app.Session.rot.loaded % structure loaded, remove all data associated with it
                resetRot(app);
                
            else
                
                %if ~app.demo.loadDemo
                % ---------------- LOAD FROM FILE ----------------
                if strcmp(loadMode,'LOAD')
                    
                    if isempty(app.Session.rot.name)
                        warningMsg = sprintf('No file selected.');
                        uialert(app.ZEALwebUIFiguree, warningMsg, 'Warning', 'icon', 'warning');
                        return
                    else
                        % <<<<<<<<< Start progressbar
                        MeStr = sprintf('Loading %s%s', app.Session.rot.name, chainStr);
                        d = uiprogressdlg(app.ZEALwebUIFiguree,'Title','Please Wait', 'Indeterminate','on','Message',MeStr);
                        
                        % Create ZEALalignment object for fixed
                        structureFile = fullfile(app.Session.rot.path, app.Session.rot.name);
                        try
                            app.ZEALrot = ZEAL('fix',structureFile, app.ZEALsettings);
                            foundFileTF = true;
                        catch ME
                            foundFileTF = false;
                            warning(ME.message);
                        end
                        close(d);% progressbar >>>>>>>>>
                        
                        if ~foundFileTF
                            warningMsg = sprintf('Could not find file. Downloaded structure %s from RCSB PDB instead', app.rot.name(1:4));
                            uialert(app.ZEALwebUIFiguree, warningMsg, 'Warning', 'icon', 'warning');
                        end
                        
                        if isempty(app.ZEALrot.fixed.PDB.Data.X)
                            errorStr = sprintf('No atom records for chain ID "%s" found in %s', chainIDsel, app.rot.name);
                            uialert(app.ZEALwebUIFiguree, errorStr, 'Loading error','icon', 'error');
                            return;
                        end
                        
                    end
                    
                    
                else % ---------------- DOWNLOAD FROM PDB ----------------
                    
                    inputOK = checkRotPDBIDinput(app);
                    
                    if inputOK
                        
                        pdbidSel = app.rotPDBIDEditField.Value;
                        app.Session.rot.name = [pdbidSel chainIDsel];
                        
                        MeStr = sprintf('Downloading %s%s from PDB', upper(pdbidSel), chainStr);
                        
                        % <<<<<<<<< Start progressbar
                        d = uiprogressdlg(app.ZEALwebUIFiguree,'Title','Please Wait', 'Indeterminate','on','Message',MeStr);
                        
                        % Create ZEALalignment object for fixed
                        try
                            app.ZEALrot = ZEAL('fix',[pdbidSel chainIDsel], app.ZEALsettings);
                            %app.ZEALrot = app.ZEALfix;
                            downloadedFileTF = true;
                        catch ME
                            downloadedFileTF = false;
                            warning(ME.message);
                        end
                        close(d);% progressbar >>>>>>>>>
                        
                        
                        % Check download and data OK
                        if ~downloadedFileTF
                            errorMsg = sprintf('Could not download structure %s from RCSB PDB', pdbidSel);
                            uialert(app.ZEALwebUIFiguree, errorMsg, 'Loading error','icon', 'error');
                            return;
                        end
                        
                        if isempty(app.ZEALrot.fixed.PDB.Data.X)
                            errorStr = sprintf('No atom records found for PDB ID "%s" and chain ID "%s"', pdbidSel, chainIDsel);
                            uialert(app.ZEALwebUIFiguree, errorStr, 'Loading error','icon', 'error');
                            return;
                        end
                    else
                        return;
                    end
                end
                
                app.Session.rot.loaded = true;
                
                %end
                
                % ---------------- update GUI ----------------
                app.importRot.BackgroundColor = [0.94 0.94 0.94];
                
                if isdeployed
                    app.importRot.Icon = 'icon-mat-delete-0072bd.png';
                else
                    app.importRot.Icon = fullfile(app.paths.icons, 'icon-mat-delete-0072bd.png');
                end
                
                app.importRot.Text = 'Delete';
                app.importRot.Tooltip = 'Reset and delete fixed structure.';
                
                app.rotCHAINIDEditField.BackgroundColor = app.Session.rotcolor;
                app.rotCHAINIDEditField.FontColor = [1 1 1];
                app.rotCHAINIDEditField.FontWeight = 'bold';
                
                app.rotPDBIDEditField.BackgroundColor = app.Session.rotcolor;
                app.rotPDBIDEditField.FontColor = [1 1 1];
                app.rotPDBIDEditField.FontWeight = 'bold';
                
                app.rotBrowseButton.Enable = false;
                
                
                % ---------------- Allow shape ZEALalignment if two structures imported ----------------
                if app.Session.fix.loaded
                    
                    % merge ZEAL single mode objects
                    app.ZEALalignment = mergeZEAL(app.ZEALalignment, app.ZEALfix, app.ZEALrot);
                    
                    app.Session.rotating = app.ZEALalignment.rotating;
                    app.Session.fixed = app.ZEALalignment.fixed;
                    
                    %computeMomentsZEALscore(app);
                    enableSearch(app);
                    enableSetupTab(app);
                    updateResultLabel(app);
                    load2JSmol(app, 'Structure','all', 'Update', true);
                else
                    load2JSmol(app,'Structure','rotating');
                end
                
            end
        end

        % Value changed function: rotSwitch
        function rotSwitchValueChanged(app, event)
            
            loadmode = app.rotSwitch.Value;
            resetRot(app);
            
            if strcmp(loadmode,'LOAD')
                
                app.rotPDBIDEditFieldLabel.Text = 'Filename';
                app.importRot.Tooltip = 'Upload pdb file for fixed structure.';
                
                if isdeployed
                    app.importRot.Icon = 'icon-mat-file-upload-0072bd.png';
                else
                    app.importRot.Icon = fullfile(app.paths.icons, 'icon-mat-file-upload-0072bd.png');
                end
                
                app.rotPDBIDEditField.Value = 'No file selected';
                app.rotBrowseButton.Visible = true;
                app.rotBrowseButton.Enable = true;
                
                app.importRot.Text = 'Upload';
                
            else
                
                app.rotPDBIDEditFieldLabel.Text = 'PDB ID code';
                app.rotPDBIDEditFieldLabel.Visible = true;
                
                app.importRot.Tooltip = 'Download fixed structure from RCSB PDB.';
                
                if isdeployed
                    app.importRot.Icon = 'icon-mat-file-download-0072bd.png';
                else
                    app.importRot.Icon = fullfile(app.paths.icons, 'icon-mat-file-download-0072bd.png');
                end
                
                app.rotPDBIDEditField.Value = '';
                
                app.rotBrowseButton.Visible = false;
                
                app.importRot.Text = 'Download';
                
            end
            
        end

        % Button pushed function: StartSearchButton
        function StartSearchButtonPushed(app, event)
            app.optimPlot.stop = false;
            app.optimPlot.iteration = 0;
            app.optimPlot.fval = -1*app.ZEALalignment.Score;
            
            globalSearch(app);
        end

        % Button pushed function: StopSearchButton
        function StopSearchButtonPushed(app, event)
            app.optimPlot.stop = true;
            app.StopSearchButton.Enable = false;
            app.StartSearchButton.Enable = true;
        end

        % Value changed function: funEvalOp_checkBox
        function funEvalOp_checkBoxValueChanged(app, event)
            
            if app.funEvalOp_checkBox.Value
                app.FunctionevaluationsEditField.Enable = true;
                app.FunctionevaluationsEditFieldLabel.Enable = true;
                
                app.ZEALalignment.Settings.SurrOpt.MaxFunctionEvaluations = app.FunctionevaluationsEditField.Value;

            else
                
                app.FunctionevaluationsEditField.Enable = false;
                app.ZEALalignment.Settings.SurrOpt.MaxFunctionEvaluations = 5e3;
            end
            
        end

        % Value changed function: execTimeOp_checkBox
        function execTimeOp_checkBoxValueChanged(app, event)
            
            if app.execTimeOp_checkBox.Value
                app.TimesEditField.Enable = true;
                app.TimesEditFieldLabel.Enable = true;
                
                app.ZEALalignment.Settings.SurrOpt.MaxTime = app.TimesEditField.Value;
            else
                
                app.TimesEditField.Enable = false;
                app.TimesEditFieldLabel.Enable = false;
                app.ZEALalignment.Settings.SurrOpt.MaxTime = 300;
            end
            
        end

        % Value changed function: ShapeRepDropDown
        function ShapeRepDropDownValueChanged(app, event)
            
            shapeType = app.ShapeRepDropDown.Value;
            
            switch shapeType
                case 1 % mol surface
                    
                    app.molSurfDropDown.Visible = true;
                    app.probeRadiusSpinner.Visible = true;
                    app.ProberadiusSpinnerLabel.Visible = true;
                    app.shellThicknessLabel.Visible = true;
                    app.shellThicknessSpinner.Visible = true;
                    
                    app.SmearfactorEditField.Visible = false;
                    app.SmearfactorEditFieldLabel.Visible = false;
                    app.RelativeisovalueEditField.Visible = false;
                    app.RelativeisovalueEditFieldLabel.Visible = false;
                    
                    app.JSmol.fix.shapeFunctionIsoVal = 0.5;
                    app.JSmol.rot.shapeFunctionIsoVal = 0.5;
                    
                case 2 % density
                    
                    app.molSurfDropDown.Visible = false;
                    app.probeRadiusSpinner.Visible = false;
                    app.ProberadiusSpinnerLabel.Visible = false;
                    app.shellThicknessLabel.Visible = false;
                    app.shellThicknessSpinner.Visible = false;
                    
                    app.SmearfactorEditField.Visible = true;
                    app.SmearfactorEditFieldLabel.Visible = true;
                    app.RelativeisovalueEditField.Visible = true;
                    app.RelativeisovalueEditFieldLabel.Visible = true;
                    
                    app.JSmol.fix.shapeFunctionIsoVal = app.RelativeisovalueEditField.Value;
                    app.JSmol.rot.shapeFunctionIsoVal = app.RelativeisovalueEditField.Value;
                    
            end
            
            % Set update flags for displaying shapeFunction in JSmol
            setShapeFunctionUpdateFlags(app, true);
            
            if app.Session.fix.loaded && app.Session.rot.loaded
                updateAllShapes(app);
            end
            
        end

        % Button pushed function: rotBrowseButton
        function rotBrowseButtonPushed(app, event)
            if ~isdeployed
                app.ZEALwebUIFiguree.Visible = 'off';
            end
            
            [app.Session.rot.name, app.Session.rot.path] = uigetfile({'*.pdb','Data Files (*.pdb)';'*.*','All Files (*.*)'},'Load pdb file ',app.Session.rot.path);
            
            if isequal(app.Session.rot.name,0) || isequal(app.Session.rot.path,0)
                
                fprintf('\nUser pressed cancel')
                
            else
                
                app.Session.fix.path = app.Session.rot.path;

                % Show fileanme in "PDB ID field"
                app.rotPDBIDEditField.Value = app.Session.rot.name;
                
            end
            
            if ~isdeployed
                app.ZEALwebUIFiguree.Visible = 'on';
            end
        end

        % Value changed function: WhereDropDown
        function WhereDropDownValueChanged(app, event)
            saveMode = app.WhereDropDown.Value;
            
            if strcmp(saveMode,'1') % save as pdb file to computer
                app.EmailaddressEditField.Visible = false;
                app.EmailaddressEditFieldLabel.Visible = false;
                app.SavePdbButton.Enable = true;
            elseif strcmp(saveMode,'2') % save pdb data as an email
                app.EmailaddressEditField.Visible = true;
                app.EmailaddressEditFieldLabel.Visible = true;
                app.EmailaddressEditField.Enable = false;
                app.SavePdbButton.Enable = false;
            end
            
        end

        % Callback function
        function horzRotSliderValueChanging(app, event)
            
            dtheta = event.Value;
            dphi = app.vertRotSlider.Value;
            
            camorbit(app.UIAxes_molViewer,dtheta,dphi, 'data',[ 0 0 1]);
            
        end

        % Callback function
        function vertRotSliderValueChanging(app, event)
            
            dtheta = app.horzRotSlider.Value;
            dphi = event.Value;
            
            camorbit(app.UIAxes_molViewer,dtheta,dphi, 'data', [0 0 1]);
            
        end

        % Callback function
        function horzRotSliderValueChanged(app, event)
            app.horzRotSlider.Value = 0;
        end

        % Callback function
        function vertRotSliderValueChanged(app, event)
            app.vertRotSlider.Value = 0;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ZEALwebUIFiguree.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {864, 864};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {453, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: previousButton
        function previousButtonPushed(app, event)
            
            app.optimPlot.solCount = max([app.optimPlot.solCount 2]) - 1;
            
            updateJSmol_OptimPlotHistory(app);
            
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            app.optimPlot.solCount = min([app.optimPlot.solCount app.optimPlot.nSolutions-1]) + 1;
            
            updateJSmol_OptimPlotHistory(app);
            
        end

        % Button pushed function: originalButton
        function originalButtonPushed(app, event)
            app.optimPlot.solCount = 1;
            
            updateJSmol_OptimPlotHistory(app);
            
        end

        % Button pushed function: finalButton
        function finalButtonPushed(app, event)
            app.optimPlot.solCount = app.optimPlot.nSolutions;
            
            updateJSmol_OptimPlotHistory(app);
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            setShapeFunctionUpdateFlags(app,true);
 
            resetSearch(app);
        end

        % Value changed function: sasurfaceButton
        function sasurfaceButtonPushed(app, event)
            
%             if app.sasurfaceButton.Value
%                 if ~app.BallandstickButton.Value || ~app.CartoonButton.Value || ~app.SpacefillButton.Value
%                     app.CartoonButton.Value = true;
%                     CartoonButtonValueChanged(app, matlab.ui.eventdata.ButtonPushedData);
%                     pause(app.JSmol.pauseTime);
%                 end
%             end
            
            JSmol_sasurfaceONOFF(app);
            
        end

        % Value changed function: CartoonButton
        function CartoonButtonValueChanged(app, event)
            
            JSmol_cartoonONOFF(app, 'all');
            
        end

        % Value changed function: SpacefillButton
        function SpacefillButtonValueChanged(app, event)
            
            pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
            
            selStr = getJSmolSelectStr(app);
            
            if ~app.TranslucentButton.Value
                if app.CartoonButton.Value
                    app.CartoonButton.Value = false;
                    
                    scriptStr = sprintf('select %s; cartoon off', selStr);
                    pdbJSmolMatStruct.scriptStr = scriptStr;
                    app.HTML.Data = pdbJSmolMatStruct;
                    pause(0.1);
                end
                
                %                 if app.sasurfaceButton.Value
                %                     app.sasurfaceButton.Value = false;
                %
                %                     scriptStr = 'select all; isosurface off';
                %                     pdbJSmolMatStruct.scriptStr = scriptStr;
                %                     app.HTML.Data = pdbJSmolMatStruct;
                %                     pause(0.1);
                %                 end
                
                if app.BallandstickButton.Value
                    app.BallandstickButton.Value = false;
                end
                
            end
            
            if app.SpacefillButton.Value
                scriptStr = 'select all; wireframe off; backbone off;wireframe off;spacefill 100%';
            else
                scriptStr = 'select all; wireframe off; backbone off;wireframe off;spacefill off';
            end
            
            fprintf('\n Sending JSmol script command:\n%s', scriptStr);
            pdbJSmolMatStruct.scriptStr = scriptStr;
            app.HTML.Data = pdbJSmolMatStruct;
            
        end

        % Value changed function: BallandstickButton
        function BallandstickButtonValueChanged(app, event)
            
            pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
            
            %             if app.CartoonButton.Value
            %                 app.CartoonButton.Value = false;
            %                 JScommand = 'x.window.eval(''Jmol.script(jmolApplet0,"select 1.1, 1.2; cartoon off;")'')';
            %                 app.win.executeJS(JScommand);
            %             end
            
            if ~app.TranslucentButton.Value
                %                 if app.sasurfaceButton.Value
                %                     app.sasurfaceButton.Value = false;
                %
                %                     scriptStr = 'isosurface off';
                %                     pdbJSmolMatStruct.scriptStr = scriptStr;
                %                     app.HTML.Data = pdbJSmolMatStruct;
                %                     pause(0.1);
                %                 end
                
                if app.SpacefillButton.Value
                    app.SpacefillButton.Value = false;
                end
                
            end
            
            if app.BallandstickButton.Value
                scriptStr = 'select all; cpk 20%; backbone off; spacefill 20%; wireframe 0.15';
            else
                scriptStr = 'select all; cpk off; backbone off; spacefill off; wireframe off';
            end
            
            fprintf('\n Sending JSmol script command:\n%s', scriptStr);
            pdbJSmolMatStruct.scriptStr = scriptStr;
            app.HTML.Data = pdbJSmolMatStruct;
            
        end

        % Value changed function: FunctionevaluationsEditField
        function FunctionevaluationsEditFieldValueChanged(app, event)
            app.ZEALalignment.Settings.SurrOpt.MaxFunctionEvaluations = app.FunctionevaluationsEditField.Value;
        end

        % Value changed function: TimesEditField
        function TimesEditFieldValueChanged(app, event)
            app.ZEALalignment.Settings.SurrOpt.MaxTime = app.TimesEditField.Value;
            
        end

        % Button pushed function: SavePdbButton
        function SavePdbButtonPushed(app, event)
            
            switch app.WhatDropDown.Value
                case 'Rotating structure'
                    saveSettings.Structure = 'rotating';
                    default_save_name = [app.ZEALalignment.rotating.PDB.Source.Name '_ZEAL.pdb'];
                    dataExist = app.Session.rot.loaded;
                case 'Fixed structure'
                    saveSettings.Structure = 'fixed';
                    default_save_name = [app.ZEALalignment.fixed.PDB.Source.Name '_ZEAL.pdb'];
                    dataExist = app.Session.fix.loaded;
            end
            
            
            % Exit if no data is available
            if ~dataExist
                errorMsg = sprintf('%s not loaded. No data to save.', app.WhatDropDown.Value);
                uialert(app.ZEALwebUIFiguree, errorMsg, 'Save error','icon', 'error');
                return
            end


            saveSettings.includeHetatoms = app.HETATOMSCheckBox.Value;
            saveSettings.includeAll = app.AllrecordsCheckBox.Value;
            
            
            switch app.WhereDropDown.Value
                
                case '1'
                    
                    % Let user pick where to save the file
                    [file,path] = uiputfile({'*.pdb','Data Files (*.pdb)';'*.*','All Files (*.*)'},'Save pdb file ',fullfile(app.Session.fix.path, default_save_name));
                    
                    if isequal(file,0) || isequal(path,0)
                        
                        return % user pressed cancel
                        
                    end
                    
                    saveSettings.folderPath = path;
                    
                    switch app.WhatDropDown.Value
                        case 'Rotating structure'
                            
                        case 'Fixed structure'
                            
                    end
                    
                    saveSettings.fixName = file;
                    
                    app.Session.fix.path = path;
                    app.Session.rot.path = path;
                    
                    save2pdb(app.ZEALalignment, saveSettings);
                    
                case '2'
                    
                    %                     pdbAsJSON = mat2JSON(pdbData);
                    %                     pdbAsStr = jsondecode(pdbAsJSON);
                    %                     pdbAsStr = pdbAsStr.pdb;
                    %
                    %                     userEmailAddress = app.EmailaddressEditField.Value;
                    %
                    %                     sendEmailCallStr = sprintf('sendemail -f ZEALalignment.andrelab@gmail.com -t %s -u %s -m "%s" -s smtp.gmail.com:587 -o tls=yes -xu ZEALalignment.andrelab@gmail.com -xp ingemaristhegreatest', userEmailAddress, default_save_name, pdbAsStr);
                    %
                    %                     if isdeployed
                    %
                    %                         [status,result] = system(sendEmailCallStr);
                    %
                    %                         fprintf('\n %s', result);
                    %
                    %                         if status==0
                    %                             uialert(app.ZEALwebUIFiguree, 'Email was send successfully!', 'Info','icon', 'info');
                    %                         else
                    %                             uialert(app.ZEALwebUIFiguree, 'Failed to send email.', 'Error','icon', 'error');
                    %
                    %                         end
                    %
                    %                     else
                    uialert(app.ZEALwebUIFiguree, 'Save option not enabled.', 'Error','icon', 'error');
                    return
                    %                     end
            end
            
            
        end

        % Value changed function: WebGLButton
        function WebGLButtonValueChanged(app, event)
            
            if app.WebGLButton.Value
                
                % Send script command
                pause(app.JSmol.pauseTime);
                pdbJSmolMatStruct.flag = '5'; % trigger JSmol script
                %pdbJSmolMatStruct.scriptStr = scriptStr;
                app.HTML.Data = pdbJSmolMatStruct;
                app.MolecularsurfaceButton.Enable = false;
                
                app.MolecularsurfaceButton.Tooltip = 'Turn off WebGL mode (Graphics tab) to enable';
                
                app.TranslucentButton.Value = false;
                app.TranslucentButton.Enable = false;
                
                app.TranslucentButton.Tooltip = 'Turn off WebGL mode (Graphics tab) to enable';
                
                app.TranslucentlevelDropDown.Enable = false;
                
            else
                % Send script command
                pause(app.JSmol.pauseTime);
                pdbJSmolMatStruct.flag = '4'; % trigger JSmol script
                
%                 pdbJSmolMatStruct.scriptStr = scriptStr;
                app.HTML.Data = pdbJSmolMatStruct;
                app.MolecularsurfaceButton.Enable = true;
                
                app.MolecularsurfaceButton.Tooltip = 'Show molecular surface (SLOW!)';
                
                app.TranslucentButton.Enable = true;
                app.TranslucentButton.Tooltip = 'Show translucent surfaces';
                
                app.TranslucentlevelDropDown.Enable = true;
                
            end
            
            pause(app.JSmol.pauseTime)
            % show as cartoon
            
            if app.Session.fix.loaded && app.Session.rot.loaded
                load2JSmol(app,'Structure','all', 'Update', true)
            else
                
                if app.Session.fix.loaded
                    load2JSmol(app,'Structure','fixed');
                elseif app.Session.rot.loaded
                    load2JSmol(app,'Structure','rotating');
                end
                
            end


        end

        % Value changed function: ResolutionDropDown
        function ResolutionDropDownValueChanged(app, event)
            
            if app.MolecularsurfaceButton.Value
                
                JSmol_molsurfaceONOFF(app);
            else
                app.sasurfaceButton.Value = true;
                JSmol_sasurfaceONOFF(app);
            end
            
        end

        % Value changed function: MolecularsurfaceButton
        function MolecularsurfaceButtonValueChanged(app, event)
            JSmol_molsurfaceONOFF(app);
        end

        % Value changed function: TranslucentButton
        function TranslucentButtonValueChanged(app, event)
            
            pdbJSmolMatStruct.flag = '2';
            
            if app.TranslucentButton.Value
                app.BlendButton.Value = true;
                
                scriptStr = 'set translucent ON';
                pdbJSmolMatStruct.scriptStr = scriptStr;
                app.HTML.Data = pdbJSmolMatStruct;
                pause(0.1);
                
                app.BlendButton.Enable = true;
                app.BlendButton.Value = true;
                
            else
                
                app.BlendButton.Value = false;
                app.BlendButton.Enable = false;
                
                scriptStr = 'set translucent OFF';
                pdbJSmolMatStruct.scriptStr = scriptStr;
                app.HTML.Data = pdbJSmolMatStruct;
                pause(0.1);
                
            end
            
            if app.MolecularsurfaceButton.Value
                JSmol_molsurfaceONOFF(app);
            else
                app.sasurfaceButton.Value = true;
                JSmol_sasurfaceONOFF(app);
            end
            
            
        end

        % Value changed function: BlendButton
        function BlendButtonValueChanged(app, event)
            
            pdbJSmolMatStruct.flag = '2';
            
            if app.BlendButton.Value
                
                scriptStr = 'set translucent ON';
                pdbJSmolMatStruct.scriptStr = scriptStr;
                app.HTML.Data = pdbJSmolMatStruct;
                pause(0.1);
                
            else
                
                scriptStr = 'set translucent OFF';
                pdbJSmolMatStruct.scriptStr = scriptStr;
                app.HTML.Data = pdbJSmolMatStruct;
                pause(0.1);
                
            end
            
            if app.MolecularsurfaceButton.Value
                
                JSmol_molsurfaceONOFF(app);
            else
                app.sasurfaceButton.Value = true;
                JSmol_sasurfaceONOFF(app);
            end
            
            
        end

        % Button pushed function: ReloadJSmolButton
        function ReloadJSmolButtonPushed(app, event)
            
            MeStr = sprintf('');
            d = uiprogressdlg(app.ZEALwebUIFiguree,'Title','Please Wait', 'Indeterminate','on','Message',MeStr);
            
            if app.Session.fix.loaded && app.Session.rot.loaded
                load2JSmol(app,'Structure','all')
            else
                
                if app.Session.fix.loaded
                    load2JSmol(app,'Structure','fixed');
                elseif app.Session.rot.loaded
                    load2JSmol(app,'Structure','rotating');
                end
                
            end
            
            app.JSmol.fix.shapeFunctionDataUpdate = true;
            app.JSmol.rot.shapeFunctionDataUpdate = true;
            
            close(d);
            
            %             if app.fix.loaded && app.rot.loaded
            %                 structureOption='all';
            %                 fixJSmolSelStr = '1.1';
            %                 rotJSmolSelStr = '1.2';
            %             else
            %                 if app.fix.loaded
            %                     structureOption='fixed';
            %                 else
            %                     structureOption='rotating';
            %                 end
            %                 fixJSmolSelStr = '1.1';
            %                 rotJSmolSelStr = '1.1';
            %             end
            %             %  --- Configure visualization styles in JSmol ---
            %             if strcmp(structureOption, 'all')
            %                 if app.CartoonButton.Value
            %                     % Set commands to JSmol
            %                     scriptStr = sprintf(['set antialiasDisplay ON; set antialiasTranslucent ON; set translucent %s;', ...
            %                         'background {0.94, 0.94, 0.94}; select 1.1, 1.2; cartoon ON; cpk off; wireframe off; frame all;', ...
            %                         '%s; color [%d %d %d]; %s; color [%d %d %d];'], ...
            %                         app.JSmol.trueTrans, ...
            %                         fixJSmolSelStr, app.JSmol.fixStructure.color(1), app.JSmol.fixStructure.color(2), app.JSmol.fixStructure.color(3), ...
            %                         rotJSmolSelStr, app.JSmol.rotStructure.color(1), app.JSmol.rotStructure.color(2), app.JSmol.rotStructure.color(3));
            %
            %                 end
            %
            %             elseif strcmp(structureOption, 'fixed')
            %
            %                 if app.CartoonButton.Value
            %                     % Set commands to JSmol
            %                     scriptStr = sprintf(['set antialiasDisplay ON; set antialiasTranslucent ON; set translucent %s;', ...
            %                         'background {0.94, 0.94, 0.94}; select 1.1; cartoon ON; cpk off; wireframe off;', ...
            %                         'select 1.1; color [%d %d %d];'], ...
            %                         app.JSmol.trueTrans, ...
            %                         app.JSmol.fixStructure.color(1), app.JSmol.fixStructure.color(2), app.JSmol.fixStructure.color(3));
            %
            %                 end
            %
            %             elseif strcmp(structureOption, 'rotating')
            %
            %                 if app.CartoonButton.Value
            %                     % Set commands to JSmol
            %                     scriptStr = sprintf(['set antialiasDisplay ON; set antialiasTranslucent ON; set translucent %s;', ...
            %                         'background {0.94, 0.94, 0.94}; select 1.1; cartoon ON; cpk off; wireframe off; frame all;', ...
            %                         'select 1.1; color [%d %d %d];'], ...
            %                         app.JSmol.trueTrans, ...
            %                         app.JSmol.rotStructure.color(1), app.JSmol.rotStructure.color(2), app.JSmol.rotStructure.color(3));
            %
            %                 end
            %
            %             end
            %
            %             scriptStr = ['load var pdbdata; ' scriptStr];
            %
            %             fprintf('\n Sending JSmol script command:\n%s', scriptStr);
            %
            %             pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
            %             pdbJSmolMatStruct.scriptStr = scriptStr;
            %             app.HTML.Data = pdbJSmolMatStruct;
        end

        % Value changed function: TranslucentlevelDropDown
        function TranslucentlevelDropDownValueChanged(app, event)
           
            scriptStr = sprintf('set defaultTranslucent %s', app.TranslucentlevelDropDown.Value);
            pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
            pdbJSmolMatStruct.scriptStr = scriptStr;
            app.HTML.Data = pdbJSmolMatStruct;
            
            if ~app.WebGLButton.Value
                
                pause(0.5);
                
                app.TranslucentButton.Value = true;
                app.sasurfaceButton.Value = true;
                
                JSmol_sasurfaceONOFF(app);
                
            end
            
        end

        % Button pushed function: LoaddemoButton
        function LoaddemoButtonPushed(app, event)
            
            app.demo.loadDemo = true;
            
            % load fixed
            app.fixPDBIDEditField.Value = app.demo.fixPDB;
            pause(app.demo.waitTime);
            app.fixCHAINIDEditField.Value = app.demo.fixPDBchain;
            pause(app.demo.waitTime);
            
            %MeStr = sprintf('Loading fixed structure: %s (chain %s)', app.demo.fixPDB, app.demo.fixPDBchain);
            %d = uiprogressdlg(app.ZEALwebUIFiguree,'Title','Please Wait', 'Indeterminate','on','Message',MeStr);
            
            importFixButtonPushed(app, matlab.ui.eventdata.ButtonPushedData);
            
            %close(d);
            
            pause(1);
            
            % load rotating
            app.rotPDBIDEditField.Value = app.demo.rotPDB;
            pause(app.demo.waitTime);
            
            app.rotCHAINIDEditField.Value = app.demo.rotPDBchain;
            pause(app.demo.waitTime);
            
            %MeStr = sprintf('Loading rotating structure: %s (chain %s)', app.demo.rotPDB, app.demo.rotPDBchain);
            %d = uiprogressdlg(app.ZEALwebUIFiguree,'Title','Please Wait', 'Indeterminate','on','Message',MeStr);
            
            importRotButtonPushed(app, matlab.ui.eventdata.ButtonPushedData);
            
            %close(d);
            
            pause(1);
            
        end

        % Value changed function: ShapefunctionButton
        function ShapefunctionButtonValueChanged(app, event)
            
            % Update shape function
            computeShapeFunction(app.ZEALalignment.rotating.molShape);
            
            disableHistory(app);
            
            enableJSmolShapeFunMode(app);
            
            % Define RGB codes in JSmol
            app.JSmol.fixStructure.color = round(app.Session.fixcolor*255);
            app.JSmol.rotStructure.color = round(app.Session.rotcolor*255);
            
            MeStr = 'Loading volume data to JSmol';
            d = uiprogressdlg(app.ZEALwebUIFiguree,'Title','Please Wait', 'Indeterminate','on','Message',MeStr);
            
            if app.Session.fix.loaded && app.Session.rot.loaded
                
                % Update volume data to JSmol
                if app.JSmol.fix.shapeFunctionDataUpdate && app.JSmol.rot.shapeFunctionDataUpdate
                    
                    app.JSmol.fix.shapeFunctionDataUpdate = false;
                    app.JSmol.rot.shapeFunctionDataUpdate = false;
                    
                    pdbJSmolMatStruct.dxData1 = ZC.mat2DXstring(app.ZEALalignment.fixed.molShape.FunctionData);
                    
                    pdbJSmolMatStruct.dxData2 = ZC.mat2DXstring(app.ZEALalignment.rotating.molShape.FunctionData);
                    
                    pdbJSmolMatStruct.flag = 7; % load DX data
                    
                    fprintf('\n Sending dx data for fixed and rotating as JSON');
                    app.HTML.Data = pdbJSmolMatStruct;
                    pause(app.JSmol.pauseTime);
                end
                
                if app.JSmol.fix.shapeFunctionCommandUpdate
                    
                    app.JSmol.fix.shapeFunctionCommandUpdate = false;
                    
                    if app.sasurfaceButton.Value
                        sasurfOFFstr = 'hide $fixSurf; hide $rotSurf';
                    else
                        sasurfOFFstr = '';
                    end
                    
                    sasurfOFFstr = 'hide all';
                    
                    %scriptStr = sprintf('%s; isosurface fix_dx model 1.1 connect {1.1} insideout cutoff %2.2f inline @dxData1; zoom 200; color $fix_dx [%d %d %d];', sasurfOFFstr, app.JSmol.fix.shapeFunctionIsoVal, app.JSmol.fixStructure.color(1), app.JSmol.fixStructure.color(2), app.JSmol.fixStructure.color(3));
                    scriptStr = sprintf('%s; isosurface fix_dx insideout cutoff %2.2f inline @dxData1; zoom 200; color $fix_dx [%d %d %d]; center $fix_dx', sasurfOFFstr, app.JSmol.fix.shapeFunctionIsoVal, app.JSmol.fixStructure.color(1), app.JSmol.fixStructure.color(2), app.JSmol.fixStructure.color(3));
                    
                else
                    
                    scriptStr = 'hide all; isosurface fix_dx on; center $fix_dx; zoom 200';
                    
                    
                end
                
                fprintf('\n Sending JSmol script command:\n%s', scriptStr);
                
                pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
                pdbJSmolMatStruct.scriptStr = scriptStr;
                app.HTML.Data = pdbJSmolMatStruct;
                
                pause(app.JSmol.pauseTime);
                
                if app.JSmol.rot.shapeFunctionCommandUpdate
                    
                    app.JSmol.rot.shapeFunctionCommandUpdate = false;
                    
                    %scriptStr = sprintf('isosurface rot_dx model 1.2 connect {1.2} insideout cutoff %2.2f inline @dxData2; zoom 200; color $rot_dx [%d %d %d]', app.JSmol.rot.shapeFunctionIsoVal, app.JSmol.rotStructure.color(1), app.JSmol.rotStructure.color(2), app.JSmol.rotStructure.color(3));
                    scriptStr = sprintf('isosurface rot_dx insideout cutoff %2.2f inline @dxData2; zoom 200; color $rot_dx [%d %d %d]; center $rot_dx', app.JSmol.rot.shapeFunctionIsoVal, app.JSmol.rotStructure.color(1), app.JSmol.rotStructure.color(2), app.JSmol.rotStructure.color(3));
                    
                else
                    scriptStr = 'hide all; isosurface rot_dx on; center $rot_dx; zoom 200';
                end
                
                fprintf('\n Sending JSmol script command:\n%s', scriptStr);
                
                pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
                pdbJSmolMatStruct.scriptStr = scriptStr;
                app.HTML.Data = pdbJSmolMatStruct;
                
                pause(app.JSmol.pauseTime);
                
            elseif app.Session.fix.loaded
                
                % Update volume data to JSmol
                if app.JSmol.fix.shapeFunctionDataUpdate
                    
                    app.JSmol.fix.shapeFunctionDataUpdate = false;
                    
                    % load fixed
                    pdbJSmolMatStruct.dxData1 = ZC.mat2DXstring(app.ZEALalignment.fixed.molShape);
                    
                    pdbJSmolMatStruct.flag = 6; % load DX data
                    
                    app.HTML.Data = pdbJSmolMatStruct;
                    
                    pause(app.JSmol.pauseTime);
                    
                end
                
                if app.JSmol.fix.shapeFunctionCommandUpdate
                    
                    app.JSmol.fix.shapeFunctionCommandUpdate = false;
                    
                    if app.sasurfaceButton.Value
                        sasurfOFFstr = 'hide $fixSurf;';
                    else
                        sasurfOFFstr = '';
                    end
                    
                    scriptStr = sprintf('hide all; %s; isosurface fix_dx insideout cutoff %2.2f inline @dxData; center $fix_dx; zoom 200; color $fix_dx [%d %d %d]', sasurfOFFstr, app.JSmol.fix.shapeFunctionIsoVal, app.JSmol.fixStructure.color(1), app.JSmol.fixStructure.color(2), app.JSmol.fixStructure.color(3));
                    fprintf('\n Sending JSmol script command:\n%s', scriptStr);
                    
                    pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
                    pdbJSmolMatStruct.scriptStr = scriptStr;
                    app.HTML.Data = pdbJSmolMatStruct;
                end
                
            elseif app.rot.loaded
                
                % Update volume data to JSmol
                if app.JSmol.rot.shapeFunctionDataUpdate
                    
                    app.JSmol.rot.shapeFunctionDataUpdate = false;
                    
                    % load rot
                    pdbJSmolMatStruct.dxData1 = ZC.mat2DXstring(app.ZEALalignment.rotating.molShape.FunctionData);
                    
                    pdbJSmolMatStruct.flag = 6; % load DX data
                    
                    app.HTML.Data = pdbJSmolMatStruct;
                    
                    pause(app.JSmol.pauseTime);
                    
                end
                
                if app.JSmol.rot.shapeFunctionCommandUpdate
                    
                    app.JSmol.rot.shapeFunctionCommandUpdate = false;
                    
                    if app.sasurfaceButton.Value
                        sasurfOFFstr = 'hide $rotSurf';
                    else
                        sasurfOFFstr = '';
                    end
                    
                    scriptStr = sprintf('hide all; %s; isosurface rot_dx insideout cutoff %2.2f inline @dxData; center $rot_dx; zoom 200; color $rot_dx [%d %d %d]', sasurfOFFstr, app.JSmol.rot.shapeFunctionIsoVal, app.JSmol.rotStructure.color(1), app.JSmol.rotStructure.color(2), app.JSmol.rotStructure.color(3));
                    fprintf('\n Sending JSmol script command:\n%s', scriptStr);
                    
                    pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
                    pdbJSmolMatStruct.scriptStr = scriptStr;
                    app.HTML.Data = pdbJSmolMatStruct;
                    
                end
                
            end
            
            %             if app.sasurfaceButton.Value
            %                 sasurfOFFstr = 'hide all; hide $fixSurf; hide $rotSurf';
            %             else
            %                 sasurfOFFstr = 'hide all';
            %             end
            %
            %             scriptStr = sprintf('%s; display $fix_dx; display $rot_dx; center $rot_dx; center $fix_dx; zoom 200;', sasurfOFFstr);
            %             fprintf('\n Sending JSmol script command:\n%s', scriptStr);
            %
            %             pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
            %             pdbJSmolMatStruct.scriptStr = scriptStr;
            %             app.HTML.Data = pdbJSmolMatStruct;
            
            close(d);
            
            pause(app.JSmol.pauseTime);
            
            
        end

        % Value changed function: MoleculesButton
        function MoleculesButtonValueChanged(app, event)
            
            if app.optimPlot.nSolutions > 1
                enableHistory(app);
            end
            
            enableJSmolMolMode(app);
            
            if ~app.JSmol.fix.shapeFunctionDataUpdate && ~app.JSmol.rot.shapeFunctionDataUpdate
                scriptStr = 'hide $fix_dx; hide $rot_dx';
                
                %             elseif  app.JSmol.fix.shapeFunctionDataUpdate
                %
                %
                %             elseif app.JSmol.rot.shapeFunctionDataUpdate
                
                
            end
            scriptStr = 'hide $fix_dx; hide $rot_dx; select all; cartoon on; display all; center; zoom 0';
            pdbJSmolMatStruct.flag = 2;
            pdbJSmolMatStruct.scriptStr = scriptStr;
            app.HTML.Data = pdbJSmolMatStruct;
            pause(0.5);
            
            
            %             if app.fix.loaded && app.rot.loaded
            %                 load2JSmol(app,'all')
            %             else
            %
            %                 if app.fix.loaded
            %                     load2JSmol(app,'fixed');
            %                 elseif app.rot.loaded
            %                     load2JSmol(app,'rotating');
            %                 end
            %
            %             end
            
        end

        % Value changed function: FixedButton
        function FixedButtonValueChanged(app, event)
            
            if app.FixedButton.Value
                scriptStr = sprintf('display $fix_dx;');
            else
                scriptStr = sprintf('hide $fix_dx;');
            end
            
            fprintf('\n Sending JSmol script command:\n%s', scriptStr);
            
            pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
            pdbJSmolMatStruct.scriptStr = scriptStr;
            app.HTML.Data = pdbJSmolMatStruct;
            
        end

        % Value changed function: RotatingButton
        function RotatingButtonValueChanged(app, event)
            
            if app.RotatingButton.Value
                scriptStr = sprintf('display $rot_dx;');
            else
                scriptStr = sprintf('hide $rot_dx;');
            end
            
            fprintf('\n Sending JSmol script command:\n%s', scriptStr);
            
            pdbJSmolMatStruct.flag = "2"; % trigger JSmol script
            pdbJSmolMatStruct.scriptStr = scriptStr;
            app.HTML.Data = pdbJSmolMatStruct;
            
        end

        % Value changed function: molSurfDropDown
        function molSurfDropDownValueChanged(app, event)
            
            % Set update flags for displaying shapeFunction in JSmol
            setShapeFunctionUpdateFlags(app, true);
            
            updateAllShapes(app);
        end

        % Value changed function: SmearfactorEditField
        function SmearfactorEditFieldValueChanged(app, event)
            % Set update flags for displaying shapeFunction in JSmol
            setShapeFunctionUpdateFlags(app, true);
            
            updateAllShapes(app);
        end

        % Value changed function: RelativeisovalueEditField
        function RelativeisovalueEditFieldValueChanged(app, event)
            
            app.JSmol.fix.shapeFunctionIsoVal = app.RelativeisovalueEditField.Value;
            app.JSmol.rot.shapeFunctionIsoVal = app.RelativeisovalueEditField.Value;
            
            % Set update flags for displaying shapeFunction in JSmol
            app.JSmol.fix.shapeFunctionCommandUpdate = true;
            app.JSmol.rot.shapeFunctionCommandUpdate = true;
            
            updateAllShapes(app);
        end

        % Value changed function: shellThicknessSpinner
        function shellThicknessSpinnerValueChanged(app, event)
            app.ZEALalignment.Settings.molShape.ShellThickness = app.shellThicknessSpinner.Value;
            % Set update flags for displaying shapeFunction in JSmol
            setShapeFunctionUpdateFlags(app, true);
            
            updateAllShapes(app);
        end

        % Value changed function: probeRadiusSpinner
        function probeRadiusSpinnerValueChanged(app, event)
            app.ZEALalignment.Settings.molShape.ProbeRadius = app.probeRadiusSpinner.Value;
            
            % Set update flags for displaying shapeFunction in JSmol
            setShapeFunctionUpdateFlags(app, true);
            
            updateAllShapes(app);
        end

        % Value changed function: ZCmomentorderDropDown
        function ZCmomentorderDropDownValueChanged(app, event)
            
            app.ZEALalignment.Settings.Order = str2double(app.ZCmomentorderDropDown.Value);
            
            reloadChiCoeffs(app.ZEALalignment, 'Order',app.ZEALalignment.Settings.Order)
                        
            updateResultLabel(app);
            
        end

        % Value changed function: GridresolutionEditField
        function GridresolutionEditFieldValueChanged(app, event)
            
            app.ZEALalignment.Settings.GridRes = app.GridresolutionEditField.Value;
            app.ZEALalignment.Settings.molShape.GridRes = app.GridresolutionEditField.Value;
            
            updateZEAL(app.ZEALalignment,'Structure','all','NewShape', true);
            
            updateResultLabel(app);
            
        end

        % Image clicked function: ZEALlogo
        function ZEALlogoImageClicked(app, event)
            web('https://academic.oup.com/bioinformatics/advance-article/doi/10.1093/bioinformatics/btab205/6194581','-browser');
        end

        % Image clicked function: article_icon
        function article_iconClicked(app, event)
            web('https://academic.oup.com/bioinformatics/advance-article/doi/10.1093/bioinformatics/btab205/6194581','-browser');
        end

        % Image clicked function: github_icon
        function github_iconImageClicked(app, event)
            web('https://github.com/Andre-lab/ZEAL_web','-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZEALwebUIFiguree = uifigure('Visible', 'off');
            app.ZEALwebUIFiguree.Name = 'MATLAB App';
            app.ZEALwebUIFiguree.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ZEALwebUIFiguree);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.IMPORTTab = uitab(app.TabGroup);
            app.IMPORTTab.Title = 'IMPORT';
            app.GridLayout5 = uigridlayout(app.IMPORTTab);
            app.FIXEDSTRUCTUREPanel = uipanel(app.GridLayout5);
            app.FIXEDSTRUCTUREPanel.Title = 'FIXED STRUCTURE';
            app.GridLayout6 = uigridlayout(app.FIXEDSTRUCTUREPanel);
            app.fixSwitch = uiswitch(app.GridLayout6, 'slider');
            app.fixSwitch.Items = {'LOAD', 'FETCH'};
            app.fixSwitch.ValueChangedFcn = createCallbackFcn(app, @fixSwitchValueChanged, true);
            app.fixSwitch.Tooltip = {''};
            app.fixSwitch.Value = 'FETCH';
            app.fixPDBIDEditField = uieditfield(app.GridLayout6, 'text');
            app.fixPDBIDEditField.Tooltip = {''};
            app.fixPDBIDEditFieldLabel = uilabel(app.GridLayout6);
            app.fixPDBIDEditFieldLabel.Text = 'PDB ID code';
            app.fixCHAINIDEditField = uieditfield(app.GridLayout6, 'text');
            app.fixCHAINIDEditField.Tooltip = {'Enter the chain ID to be imported. All chains will be imported if the field is empty.'};
            app.CHAINIDEditFieldLabel = uilabel(app.GridLayout6);
            app.CHAINIDEditFieldLabel.Text = 'CHAIN code';
            app.importFix = uibutton(app.GridLayout6, 'push');
            app.importFix.ButtonPushedFcn = createCallbackFcn(app, @importFixButtonPushed, true);
            app.importFix.Icon = 'icon-mat-file-download-0072bd.png';
            app.importFix.Tooltip = {'Load pdb file for fixed structure.'};
            app.importFix.Text = 'Download';
            app.fixBrowseButton = uibutton(app.GridLayout6, 'push');
            app.fixBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @fixBrowseButtonPushed, true);
            app.fixBrowseButton.Text = 'Browse';
            app.ROTATINGSTRUCTUREPanel = uipanel(app.GridLayout5);
            app.ROTATINGSTRUCTUREPanel.Title = 'ROTATING STRUCTURE';
            app.GridLayout7 = uigridlayout(app.ROTATINGSTRUCTUREPanel);
            app.importRot = uibutton(app.GridLayout7, 'push');
            app.importRot.ButtonPushedFcn = createCallbackFcn(app, @importRotButtonPushed, true);
            app.importRot.Icon = 'icon-mat-file-download-0072bd.png';
            app.importRot.Tooltip = {'Load pdb file for rotating structure.'};
            app.importRot.Text = 'Download';
            app.rotSwitch = uiswitch(app.GridLayout7, 'slider');
            app.rotSwitch.Items = {'LOAD', 'FETCH'};
            app.rotSwitch.ValueChangedFcn = createCallbackFcn(app, @rotSwitchValueChanged, true);
            app.rotSwitch.Tooltip = {''};
            app.rotSwitch.Value = 'FETCH';
            app.rotPDBIDEditFieldLabel = uilabel(app.GridLayout7);
            app.rotPDBIDEditFieldLabel.Text = 'PDB ID code';
            app.rotCHAINIDEditFieldLabel = uilabel(app.GridLayout7);
            app.rotCHAINIDEditFieldLabel.Text = 'CHAIN code';
            app.rotCHAINIDEditField = uieditfield(app.GridLayout7, 'text');
            app.rotCHAINIDEditField.Tooltip = {'Enter the chain ID to be imported. All chains will be imported if the field is empty.'};
            app.rotPDBIDEditField = uieditfield(app.GridLayout7, 'text');
            app.rotPDBIDEditField.Tooltip = {''};
            app.rotBrowseButton = uibutton(app.GridLayout7, 'push');
            app.rotBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @rotBrowseButtonPushed, true);
            app.rotBrowseButton.Text = 'Browse';
            app.LoaddemoButton = uibutton(app.GridLayout5, 'push');
            app.LoaddemoButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddemoButtonPushed, true);
            app.LoaddemoButton.Text = 'Load demo';
            app.ALIGNTab = uitab(app.TabGroup);
            app.ALIGNTab.Title = 'ALIGN';
            app.GridLayout4 = uigridlayout(app.ALIGNTab);
            app.StartSearchButton = uibutton(app.GridLayout4, 'push');
            app.StartSearchButton.ButtonPushedFcn = createCallbackFcn(app, @StartSearchButtonPushed, true);
            app.StartSearchButton.Icon = 'icon-mw-matlab-0072bd.png';
            app.StartSearchButton.Text = 'Start Search';
            app.StopSearchButton = uibutton(app.GridLayout4, 'push');
            app.StopSearchButton.ButtonPushedFcn = createCallbackFcn(app, @StopSearchButtonPushed, true);
            app.StopSearchButton.Icon = 'icon-mat-stop-a2142f.png';
            app.StopSearchButton.Enable = 'off';
            app.StopSearchButton.Text = 'Stop Search';
            app.STOPPINGCRITERIAPanel = uipanel(app.GridLayout4);
            app.STOPPINGCRITERIAPanel.Title = 'STOPPING CRITERIA';
            app.GridLayout23 = uigridlayout(app.STOPPINGCRITERIAPanel);
            app.funEvalOp_checkBox = uicheckbox(app.GridLayout23);
            app.funEvalOp_checkBox.ValueChangedFcn = createCallbackFcn(app, @funEvalOp_checkBoxValueChanged, true);
            app.funEvalOp_checkBox.Text = '';
            app.funEvalOp_checkBox.Value = true;
            app.execTimeOp_checkBox = uicheckbox(app.GridLayout23);
            app.execTimeOp_checkBox.ValueChangedFcn = createCallbackFcn(app, @execTimeOp_checkBoxValueChanged, true);
            app.execTimeOp_checkBox.Text = '';
            app.FunctionevaluationsEditFieldLabel = uilabel(app.GridLayout23);
            app.FunctionevaluationsEditFieldLabel.Text = 'Function evaluations';
            app.FunctionevaluationsEditField = uieditfield(app.GridLayout23, 'numeric');
            app.FunctionevaluationsEditField.Limits = [25 10000];
            app.FunctionevaluationsEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionevaluationsEditFieldValueChanged, true);
            app.FunctionevaluationsEditField.Value = 300;
            app.TimesEditFieldLabel = uilabel(app.GridLayout23);
            app.TimesEditFieldLabel.Enable = 'off';
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimesEditField = uieditfield(app.GridLayout23, 'numeric');
            app.TimesEditField.Limits = [10 1000];
            app.TimesEditField.ValueChangedFcn = createCallbackFcn(app, @TimesEditFieldValueChanged, true);
            app.TimesEditField.Enable = 'off';
            app.TimesEditField.Value = 60;
            app.Panel_4 = uipanel(app.GridLayout4);
            app.GridLayout22 = uigridlayout(app.Panel_4);
            app.previousButton = uibutton(app.GridLayout22, 'push');
            app.previousButton.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previousButton.Icon = 'icon-mat-keyboard-arrow-left-0072bd.png';
            app.previousButton.Enable = 'off';
            app.previousButton.Tooltip = {'Previous orientation in search'};
            app.previousButton.Text = '';
            app.Label = uilabel(app.GridLayout22);
            app.Label.Text = '';
            app.nextButton = uibutton(app.GridLayout22, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Icon = 'icon-mat-keyboard-arrow-right-0072bd.png';
            app.nextButton.Enable = 'off';
            app.nextButton.Tooltip = {'Next orientation in search'};
            app.nextButton.Text = '';
            app.originalButton = uibutton(app.GridLayout22, 'push');
            app.originalButton.ButtonPushedFcn = createCallbackFcn(app, @originalButtonPushed, true);
            app.originalButton.Icon = 'icon-mat-first-page-0072bd.png';
            app.originalButton.Enable = 'off';
            app.originalButton.Tooltip = {'Orientation at beginning of search'};
            app.originalButton.Text = '';
            app.finalButton = uibutton(app.GridLayout22, 'push');
            app.finalButton.ButtonPushedFcn = createCallbackFcn(app, @finalButtonPushed, true);
            app.finalButton.Icon = 'icon-mat-last-page-0072bd.png';
            app.finalButton.Enable = 'off';
            app.finalButton.Tooltip = {'Orientation after search'};
            app.finalButton.Text = '';
            app.resetButton = uibutton(app.GridLayout22, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Icon = 'icon-custom-cancel-0072bd.png';
            app.resetButton.Enable = 'off';
            app.resetButton.Tooltip = {'Reset'};
            app.resetButton.Text = '';
            app.UIAxes_optimPlot = uiaxes(app.GridLayout4);
            title(app.UIAxes_optimPlot, '')
            xlabel(app.UIAxes_optimPlot, 'function evaluations')
            ylabel(app.UIAxes_optimPlot, 'ZEAL score')
            app.EXPORTTab = uitab(app.TabGroup);
            app.EXPORTTab.Title = 'EXPORT';
            app.GridLayout11 = uigridlayout(app.EXPORTTab);
            app.Panel = uipanel(app.GridLayout11);
            app.GridLayout12 = uigridlayout(app.Panel);
            app.WHEREPanel = uipanel(app.GridLayout12);
            app.WHEREPanel.Title = 'WHERE?';
            app.GridLayout17 = uigridlayout(app.WHEREPanel);
            app.WhereDropDown = uidropdown(app.GridLayout17);
            app.WhereDropDown.Items = {'Save to computer', 'Save to email'};
            app.WhereDropDown.ItemsData = {'1', '2'};
            app.WhereDropDown.ValueChangedFcn = createCallbackFcn(app, @WhereDropDownValueChanged, true);
            app.WhereDropDown.Value = '1';
            app.EmailaddressEditFieldLabel = uilabel(app.GridLayout17);
            app.EmailaddressEditFieldLabel.Text = 'Email address';
            app.EmailaddressEditField = uieditfield(app.GridLayout17, 'text');
            app.SavePdbButton = uibutton(app.GridLayout12, 'push');
            app.SavePdbButton.ButtonPushedFcn = createCallbackFcn(app, @SavePdbButtonPushed, true);
            app.SavePdbButton.Tooltip = {'Save aligned structures as new pdb files. '};
            app.SavePdbButton.Text = 'SAVE';
            app.WHATPanel = uipanel(app.GridLayout12);
            app.WHATPanel.Title = 'WHAT?';
            app.GridLayout18 = uigridlayout(app.WHATPanel);
            app.WhatDropDown = uidropdown(app.GridLayout18);
            app.WhatDropDown.Items = {'Rotating structure', 'Fixed structure'};
            app.WhatDropDown.Value = 'Rotating structure';
            app.HETATOMSCheckBox = uicheckbox(app.GridLayout18);
            app.HETATOMSCheckBox.Text = 'Include HETATOMS';
            app.HETATOMSCheckBox.Value = true;
            app.AllrecordsCheckBox = uicheckbox(app.GridLayout18);
            app.AllrecordsCheckBox.Text = 'Include all records';
            app.SETTINGSTab = uitab(app.TabGroup);
            app.SETTINGSTab.Title = 'SETTINGS';
            app.GridLayout13 = uigridlayout(app.SETTINGSTab);
            app.DZERNIKEPARAMEERSPanel = uipanel(app.GridLayout13);
            app.DZERNIKEPARAMEERSPanel.Title = '3D ZERNIKE PARAMEERS';
            app.GridLayout14 = uigridlayout(app.DZERNIKEPARAMEERSPanel);
            app.GridresolutionLabel = uilabel(app.GridLayout14);
            app.GridresolutionLabel.Text = 'Grid resolution';
            app.GridresolutionEditField = uieditfield(app.GridLayout14, 'numeric');
            app.GridresolutionEditField.Limits = [32 400];
            app.GridresolutionEditField.ValueChangedFcn = createCallbackFcn(app, @GridresolutionEditFieldValueChanged, true);
            app.GridresolutionEditField.Tooltip = {'The molecular surface is computed using a grid. The higher the resolution'; ' the higher the level of structural surface details. '};
            app.GridresolutionEditField.Value = 64;
            app.ZCmomentorderDropDownLabel = uilabel(app.GridLayout14);
            app.ZCmomentorderDropDownLabel.Text = 'Expansion order';
            app.ZCmomentorderDropDown = uidropdown(app.GridLayout14);
            app.ZCmomentorderDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '25', '30'};
            app.ZCmomentorderDropDown.ValueChangedFcn = createCallbackFcn(app, @ZCmomentorderDropDownValueChanged, true);
            app.ZCmomentorderDropDown.Value = '20';
            app.SHAPEFUNCTIONPanel = uipanel(app.GridLayout13);
            app.SHAPEFUNCTIONPanel.Title = 'SHAPE FUNCTION';
            app.GridLayout15 = uigridlayout(app.SHAPEFUNCTIONPanel);
            app.molSurfDropDown = uidropdown(app.GridLayout15);
            app.molSurfDropDown.Items = {'Solvent excluded ', 'Solvent accessible', ''};
            app.molSurfDropDown.ItemsData = {'1', '2'};
            app.molSurfDropDown.Editable = 'on';
            app.molSurfDropDown.ValueChangedFcn = createCallbackFcn(app, @molSurfDropDownValueChanged, true);
            app.molSurfDropDown.Enable = 'off';
            app.molSurfDropDown.Value = '1';
            app.ShapeRepDropDown = uidropdown(app.GridLayout15);
            app.ShapeRepDropDown.Items = {'Molecular surface', 'Density'};
            app.ShapeRepDropDown.ItemsData = {'1', '2'};
            app.ShapeRepDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeRepDropDownValueChanged, true);
            app.ShapeRepDropDown.Value = '1';
            app.Panel_2 = uipanel(app.GridLayout15);
            app.GridLayout16 = uigridlayout(app.Panel_2);
            app.SmearfactorEditField = uieditfield(app.GridLayout16, 'numeric');
            app.SmearfactorEditField.Limits = [0 1];
            app.SmearfactorEditField.ValueChangedFcn = createCallbackFcn(app, @SmearfactorEditFieldValueChanged, true);
            app.SmearfactorEditField.Enable = 'off';
            app.SmearfactorEditField.Tooltip = {'Fraction of the grid to smear an atom over.'};
            app.SmearfactorEditField.Value = 0.3;
            app.SmearfactorEditFieldLabel = uilabel(app.GridLayout16);
            app.SmearfactorEditFieldLabel.Enable = 'off';
            app.SmearfactorEditFieldLabel.Text = 'Smear factor';
            app.RelativeisovalueEditField = uieditfield(app.GridLayout16, 'numeric');
            app.RelativeisovalueEditField.Limits = [0 Inf];
            app.RelativeisovalueEditField.ValueChangedFcn = createCallbackFcn(app, @RelativeisovalueEditFieldValueChanged, true);
            app.RelativeisovalueEditField.Enable = 'off';
            app.RelativeisovalueEditField.Value = 0.1;
            app.RelativeisovalueEditFieldLabel = uilabel(app.GridLayout16);
            app.RelativeisovalueEditFieldLabel.Enable = 'off';
            app.RelativeisovalueEditFieldLabel.Text = 'Relative iso value';
            app.shellThicknessLabel = uilabel(app.GridLayout16);
            app.shellThicknessLabel.Enable = 'off';
            app.shellThicknessLabel.Text = 'Thickness (grid units)';
            app.shellThicknessSpinner = uispinner(app.GridLayout16);
            app.shellThicknessSpinner.Limits = [0 Inf];
            app.shellThicknessSpinner.ValueDisplayFormat = '%.0f';
            app.shellThicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @shellThicknessSpinnerValueChanged, true);
            app.shellThicknessSpinner.Enable = 'off';
            app.shellThicknessSpinner.Value = 2;
            app.ProberadiusSpinnerLabel = uilabel(app.GridLayout16);
            app.ProberadiusSpinnerLabel.Enable = 'off';
            app.ProberadiusSpinnerLabel.Text = 'Probe radius (Å)';
            app.probeRadiusSpinner = uispinner(app.GridLayout16);
            app.probeRadiusSpinner.Step = 0.1;
            app.probeRadiusSpinner.Limits = [0 10];
            app.probeRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @probeRadiusSpinnerValueChanged, true);
            app.probeRadiusSpinner.Enable = 'off';
            app.probeRadiusSpinner.Value = 1.4;
            app.USEREGIONOFINTERESTPanel = uipanel(app.GridLayout13);
            app.USEREGIONOFINTERESTPanel.Title = 'USE REGION OF INTEREST';
            app.GridLayout19 = uigridlayout(app.USEREGIONOFINTERESTPanel);
            app.fixROICheckBox = uicheckbox(app.GridLayout19);
            app.fixROICheckBox.Enable = 'off';
            app.fixROICheckBox.Text = 'Fixed';
            app.rotROICheckBox = uicheckbox(app.GridLayout19);
            app.rotROICheckBox.Enable = 'off';
            app.rotROICheckBox.Text = 'Rotating';
            app.GRAPHICSTab = uitab(app.TabGroup);
            app.GRAPHICSTab.Title = 'GRAPHICS';
            app.GridLayout28 = uigridlayout(app.GRAPHICSTab);
            app.JSMOLPanel = uipanel(app.GridLayout28);
            app.JSMOLPanel.Title = 'JSMOL';
            app.GridLayout29 = uigridlayout(app.JSMOLPanel);
            app.WebGLButton = uibutton(app.GridLayout29, 'state');
            app.WebGLButton.ValueChangedFcn = createCallbackFcn(app, @WebGLButtonValueChanged, true);
            app.WebGLButton.Text = 'WebGL';
            app.WebGLButton.Value = true;
            app.ModeLabel = uilabel(app.GridLayout29);
            app.ModeLabel.Text = 'Mode';
            app.SurfacePanel = uipanel(app.GridLayout29);
            app.SurfacePanel.Title = 'Surface';
            app.GridLayout30 = uigridlayout(app.SurfacePanel);
            app.ResolutionDropDownLabel = uilabel(app.GridLayout30);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.GridLayout30);
            app.ResolutionDropDown.Items = {'Low', 'Medium', 'High'};
            app.ResolutionDropDown.ItemsData = {'0.5', '1', '2'};
            app.ResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionDropDownValueChanged, true);
            app.ResolutionDropDown.Value = '1';
            app.TranslucentButton = uibutton(app.GridLayout30, 'state');
            app.TranslucentButton.ValueChangedFcn = createCallbackFcn(app, @TranslucentButtonValueChanged, true);
            app.TranslucentButton.Enable = 'off';
            app.TranslucentButton.Text = 'Translucent';
            app.BlendButton = uibutton(app.GridLayout30, 'state');
            app.BlendButton.ValueChangedFcn = createCallbackFcn(app, @BlendButtonValueChanged, true);
            app.BlendButton.Enable = 'off';
            app.BlendButton.Text = 'Blend';
            app.BlendButton.Value = true;
            app.ReloadJSmolButton = uibutton(app.GridLayout29, 'push');
            app.ReloadJSmolButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadJSmolButtonPushed, true);
            app.ReloadJSmolButton.Text = 'Reload JSmol';
            app.TranslucentlevelDropDownLabel = uilabel(app.GridLayout29);
            app.TranslucentlevelDropDownLabel.Text = 'Translucent level';
            app.TranslucentlevelDropDown = uidropdown(app.GridLayout29);
            app.TranslucentlevelDropDown.Items = {'0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9'};
            app.TranslucentlevelDropDown.ItemsData = {'0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9'};
            app.TranslucentlevelDropDown.ValueChangedFcn = createCallbackFcn(app, @TranslucentlevelDropDownValueChanged, true);
            app.TranslucentlevelDropDown.Enable = 'off';
            app.TranslucentlevelDropDown.Value = '0.5';
            app.ZEALlogo = uiimage(app.GridLayout3);
            app.ZEALlogo.ImageClickedFcn = createCallbackFcn(app, @ZEALlogoImageClicked, true);
            app.ZEALlogo.Tooltip = {'https://doi.org/10.1093/bioinformatics/btab205'};
            app.ZEALlogo.ImageSource = 'zealLogo.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.JSMOLTab = uitab(app.TabGroup2);
            app.JSMOLTab.Title = 'JSMOL';
            app.GridLayout20 = uigridlayout(app.JSMOLTab);
            app.HTML = uihtml(app.GridLayout20);
            app.Panel_5 = uipanel(app.GridLayout20);
            app.GridLayout26 = uigridlayout(app.Panel_5);
            app.ZEALscoreResultLabel_2 = uilabel(app.GridLayout26);
            app.ZEALscoreResultLabel_2.Text = 'Score: NA';
            app.MoleculesButton = uibutton(app.GridLayout26, 'state');
            app.MoleculesButton.ValueChangedFcn = createCallbackFcn(app, @MoleculesButtonValueChanged, true);
            app.MoleculesButton.Text = 'Molecules';
            app.MoleculesButton.Value = true;
            app.ShapefunctionButton = uibutton(app.GridLayout26, 'state');
            app.ShapefunctionButton.ValueChangedFcn = createCallbackFcn(app, @ShapefunctionButtonValueChanged, true);
            app.ShapefunctionButton.Text = 'Shape function';
            app.Panel_6 = uipanel(app.GridLayout20);
            app.GridLayout27 = uigridlayout(app.Panel_6);
            app.CartoonButton = uibutton(app.GridLayout27, 'state');
            app.CartoonButton.ValueChangedFcn = createCallbackFcn(app, @CartoonButtonValueChanged, true);
            app.CartoonButton.Text = 'Cartoon';
            app.CartoonButton.Value = true;
            app.BallandstickButton = uibutton(app.GridLayout27, 'state');
            app.BallandstickButton.ValueChangedFcn = createCallbackFcn(app, @BallandstickButtonValueChanged, true);
            app.BallandstickButton.Text = 'Ball and stick';
            app.SpacefillButton = uibutton(app.GridLayout27, 'state');
            app.SpacefillButton.ValueChangedFcn = createCallbackFcn(app, @SpacefillButtonValueChanged, true);
            app.SpacefillButton.Text = 'Spacefill';
            app.sasurfaceButton = uibutton(app.GridLayout27, 'state');
            app.sasurfaceButton.ValueChangedFcn = createCallbackFcn(app, @sasurfaceButtonPushed, true);
            app.sasurfaceButton.Text = 'Solvent accessible surface';
            app.MolecularsurfaceButton = uibutton(app.GridLayout27, 'state');
            app.MolecularsurfaceButton.ValueChangedFcn = createCallbackFcn(app, @MolecularsurfaceButtonValueChanged, true);
            app.MolecularsurfaceButton.Enable = 'off';
            app.MolecularsurfaceButton.Tooltip = {'Turn off WebGL mode (Graphics tab) to enable'};
            app.MolecularsurfaceButton.Text = 'Molecular surface';
            app.FixedButton = uibutton(app.GridLayout27, 'state');
            app.FixedButton.ValueChangedFcn = createCallbackFcn(app, @FixedButtonValueChanged, true);
            app.FixedButton.Enable = 'off';
            app.FixedButton.Visible = 'off';
            app.FixedButton.Text = 'Fixed';
            app.RotatingButton = uibutton(app.GridLayout27, 'state');
            app.RotatingButton.ValueChangedFcn = createCallbackFcn(app, @RotatingButtonValueChanged, true);
            app.RotatingButton.Enable = 'off';
            app.RotatingButton.Visible = 'off';
            app.RotatingButton.Text = 'Rotating';
            app.waitSpinnerImage = uiimage(app.GridLayout20);
            app.waitSpinnerImage.Visible = 'off';
            app.waitSpinnerImage.ImageSource = 'waitSpinner.gif';
            app.GridLayout31 = uigridlayout(app.GridLayout2);
            app.Label_3 = uilabel(app.GridLayout31);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.GridLayout31);
            app.Label_2.Text = '';
            app.article_icon = uiimage(app.GridLayout31);
            app.article_icon.ImageClickedFcn = createCallbackFcn(app, @article_iconClicked, true);
            app.article_icon.Tooltip = {'https://doi.org/10.1093/bioinformatics/btab205'};
            app.article_icon.ImageSource = 'icon-mat-document-0072bd.png';
            app.FilipLjungandIngemarAndrBioinformatics2021Label = uilabel(app.GridLayout31);
            app.FilipLjungandIngemarAndrBioinformatics2021Label.Text = 'Filip Ljung and Ingemar André, Bioinformatics (2021)';
            app.github_icon = uiimage(app.GridLayout31);
            app.github_icon.ImageClickedFcn = createCallbackFcn(app, @github_iconImageClicked, true);
            app.github_icon.Tooltip = {'https://github.com/Andre-lab/ZEAL_web'};
            app.github_icon.ImageSource = 'GitHub-Mark-32px.png';
            % Show the figure after all components are created
            app.ZEALwebUIFiguree.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZEALweb

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZEALwebUIFiguree)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZEALwebUIFiguree)
        end
    end
end